classdef T1mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappUIFigure               matlab.ui.Figure
        Hyperlink                    matlab.ui.control.Hyperlink
        CreditsLabel                 matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        StatusImage                  matlab.ui.control.Image
        BackgroundBox                matlab.ui.control.TextArea
        MessageBox                   matlab.ui.control.TextArea
        TabGroup4                    matlab.ui.container.TabGroup
        SequenceParametersTab        matlab.ui.container.Tab
        NrTRsViewField               matlab.ui.control.NumericEditField
        NrTRsViewFieldLabel          matlab.ui.control.Label
        TRsEditField                 matlab.ui.control.NumericEditField
        TRsEditFieldLabel            matlab.ui.control.Label
        SLTViewField                 matlab.ui.control.NumericEditField
        SLTViewFieldLabel            matlab.ui.control.Label
        AspectRatioViewField         matlab.ui.control.NumericEditField
        AspectRatioViewFieldLabel    matlab.ui.control.Label
        SlicesViewField              matlab.ui.control.NumericEditField
        SlicesViewFieldLabel         matlab.ui.control.Label
        NrDynamicsViewField          matlab.ui.control.NumericEditField
        NrDynamicsViewFieldLabel     matlab.ui.control.Label
        NrTIsViewField               matlab.ui.control.NumericEditField
        NrTIsViewFieldLabel          matlab.ui.control.Label
        dimyViewField                matlab.ui.control.NumericEditField
        dimyViewFieldLabel           matlab.ui.control.Label
        TIsEditField                 matlab.ui.control.NumericEditField
        TIsEditFieldLabel            matlab.ui.control.Label
        TI0EditField                 matlab.ui.control.NumericEditField
        TI0EditFieldLabel            matlab.ui.control.Label
        dimxViewField                matlab.ui.control.NumericEditField
        dimxViewFieldLabel           matlab.ui.control.Label
        TR0ViewField                 matlab.ui.control.NumericEditField
        TR0ViewFieldLabel            matlab.ui.control.Label
        FOVViewField                 matlab.ui.control.NumericEditField
        FOVViewFieldLabel            matlab.ui.control.Label
        DatafileEditField            matlab.ui.control.EditField
        DatafileEditFieldLabel       matlab.ui.control.Label
        TabGroup3                    matlab.ui.container.TabGroup
        T1mapTab                     matlab.ui.container.Tab
        ExportRoiDataButton          matlab.ui.control.Button
        T1ViewField2                 matlab.ui.control.EditField
        T1ViewField2Label            matlab.ui.control.Label
        T1ROIAveragedLabel           matlab.ui.control.Label
        T1ViewField1                 matlab.ui.control.EditField
        T1ViewField1Label            matlab.ui.control.Label
        T1PixelValueLabel            matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        BrushEditField               matlab.ui.control.NumericEditField
        BrushEditFieldLabel          matlab.ui.control.Label
        ROIbutton                    matlab.ui.control.Button
        EraseButton                  matlab.ui.control.Button
        T1ColormapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton                 matlab.ui.control.RadioButton
        parulaButton                 matlab.ui.control.RadioButton
        winterButton                 matlab.ui.control.RadioButton
        T1ScaleAutoButton            matlab.ui.control.Button
        autumnButton                 matlab.ui.control.RadioButton
        T1ScaleMinEditField          matlab.ui.control.NumericEditField
        T1ScaleMinLabel              matlab.ui.control.Label
        T1ScaleEditField             matlab.ui.control.NumericEditField
        grayButton                   matlab.ui.control.RadioButton
        T1ScaleMaxLabel              matlab.ui.control.Label
        T1ImageSelectionPanel        matlab.ui.container.Panel
        DynamicsSlider2              matlab.ui.control.Slider
        DynamicsEditField2           matlab.ui.control.NumericEditField
        DynamicsEditField2Label      matlab.ui.control.Label
        SlicesSlider2                matlab.ui.control.Slider
        SlicesEditField2             matlab.ui.control.NumericEditField
        SlicesEditField2Label        matlab.ui.control.Label
        T1Image                      matlab.ui.control.Image
        T1MapLabel                   matlab.ui.control.Label
        T1linearPlot                 matlab.ui.control.UIAxes
        T1MapFig                     matlab.ui.control.UIAxes
        M0R2mapTab                   matlab.ui.container.Tab
        R2ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton3                matlab.ui.control.RadioButton
        parulaButton3                matlab.ui.control.RadioButton
        winterButton3                matlab.ui.control.RadioButton
        autumnButton3                matlab.ui.control.RadioButton
        grayButton3                  matlab.ui.control.RadioButton
        BrushEditField2              matlab.ui.control.NumericEditField
        BrushEditField2Label         matlab.ui.control.Label
        R2Image                      matlab.ui.control.Image
        ROIbutton2                   matlab.ui.control.Button
        EraseButton2                 matlab.ui.control.Button
        R2MapLabel                   matlab.ui.control.Label
        M0ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        lipariButton2                matlab.ui.control.RadioButton
        parulaButton2                matlab.ui.control.RadioButton
        winterButton2                matlab.ui.control.RadioButton
        autumnButton2                matlab.ui.control.RadioButton
        grayButton2                  matlab.ui.control.RadioButton
        M0ImageSelectionPanel        matlab.ui.container.Panel
        DynamicsSlider3              matlab.ui.control.Slider
        DynamicsEditField3           matlab.ui.control.NumericEditField
        DynamicsEditField3Label      matlab.ui.control.Label
        SlicesSlider3                matlab.ui.control.Slider
        SlicesEditField3             matlab.ui.control.NumericEditField
        SlicesEditField3Label        matlab.ui.control.Label
        M0Image                      matlab.ui.control.Image
        M0MapLabel                   matlab.ui.control.Label
        R2MapFig                     matlab.ui.control.UIAxes
        M0MapFig                     matlab.ui.control.UIAxes
        TabGroup2                    matlab.ui.container.TabGroup
        RawImagesTab                 matlab.ui.container.Tab
        LevelSlider                  matlab.ui.control.Slider
        WindowSlider                 matlab.ui.control.Slider
        AutoLWButton                 matlab.ui.control.StateButton
        LevelEditField               matlab.ui.control.NumericEditField
        LevelEditFieldLabel          matlab.ui.control.Label
        WindowEditField              matlab.ui.control.NumericEditField
        WindowEditFieldLabel         matlab.ui.control.Label
        MaskSettingsPanel            matlab.ui.container.Panel
        MaskSizeEditField            matlab.ui.control.NumericEditField
        UndoButton                   matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        DilateButton                 matlab.ui.control.Button
        ErodeButton                  matlab.ui.control.Button
        ThresholdSlider              matlab.ui.control.Slider
        thresholdEditField           matlab.ui.control.NumericEditField
        thresholdEditFieldLabel      matlab.ui.control.Label
        ImageSelectionPanel          matlab.ui.container.Panel
        DynamicsSlider1              matlab.ui.control.Slider
        DynamicsEditField1           matlab.ui.control.NumericEditField
        DynamicsEditField1Label      matlab.ui.control.Label
        InversionSlider              matlab.ui.control.Slider
        InversionEditField           matlab.ui.control.NumericEditField
        InversionEditFieldLabel      matlab.ui.control.Label
        SlicesSlider1                matlab.ui.control.Slider
        SlicesEditField1             matlab.ui.control.NumericEditField
        SlicesEditField1Label        matlab.ui.control.Label
        RawImage                     matlab.ui.control.Image
        ImageLabel                   matlab.ui.control.Label
        RawDataFig                   matlab.ui.control.UIAxes
        RecoParametersTab            matlab.ui.container.Tab
        CSregularizationPanel        matlab.ui.container.Panel
        TVdynEditField               matlab.ui.control.NumericEditField
        TVdynEditFieldLabel          matlab.ui.control.Label
        TVtiEditField                matlab.ui.control.NumericEditField
        TVtiEditFieldLabel           matlab.ui.control.Label
        LLRxyzEditField              matlab.ui.control.NumericEditField
        LLRxyzEditFieldLabel         matlab.ui.control.Label
        TVxyzEditField               matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel          matlab.ui.control.Label
        WVxyzEditField               matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel          matlab.ui.control.Label
        FilteringPanel               matlab.ui.container.Panel
        DeRingCheckBox               matlab.ui.control.CheckBox
        DeRingCheckBoxLabel          matlab.ui.control.Label
        DeNoiseCheckBox              matlab.ui.control.CheckBox
        DeNoiseCheckBoxLabel         matlab.ui.control.Label
        FitParametersTab             matlab.ui.container.Tab
        InversionTimeSelectionPanel  matlab.ui.container.Panel
        CheckBox_96                  matlab.ui.control.CheckBox
        CheckBox_88                  matlab.ui.control.CheckBox
        CheckBox_80                  matlab.ui.control.CheckBox
        CheckBox_72                  matlab.ui.control.CheckBox
        CheckBox_64                  matlab.ui.control.CheckBox
        CheckBox_56                  matlab.ui.control.CheckBox
        CheckBox_48                  matlab.ui.control.CheckBox
        CheckBox_40                  matlab.ui.control.CheckBox
        CheckBox_32                  matlab.ui.control.CheckBox
        CheckBox_24                  matlab.ui.control.CheckBox
        CheckBox_16                  matlab.ui.control.CheckBox
        CheckBox_8                   matlab.ui.control.CheckBox
        CheckBox_95                  matlab.ui.control.CheckBox
        CheckBox_87                  matlab.ui.control.CheckBox
        CheckBox_79                  matlab.ui.control.CheckBox
        CheckBox_71                  matlab.ui.control.CheckBox
        CheckBox_63                  matlab.ui.control.CheckBox
        CheckBox_55                  matlab.ui.control.CheckBox
        CheckBox_47                  matlab.ui.control.CheckBox
        CheckBox_39                  matlab.ui.control.CheckBox
        CheckBox_31                  matlab.ui.control.CheckBox
        CheckBox_23                  matlab.ui.control.CheckBox
        CheckBox_15                  matlab.ui.control.CheckBox
        CheckBox_7                   matlab.ui.control.CheckBox
        CheckBox_94                  matlab.ui.control.CheckBox
        CheckBox_86                  matlab.ui.control.CheckBox
        CheckBox_78                  matlab.ui.control.CheckBox
        CheckBox_70                  matlab.ui.control.CheckBox
        CheckBox_62                  matlab.ui.control.CheckBox
        CheckBox_54                  matlab.ui.control.CheckBox
        CheckBox_46                  matlab.ui.control.CheckBox
        CheckBox_38                  matlab.ui.control.CheckBox
        CheckBox_30                  matlab.ui.control.CheckBox
        CheckBox_22                  matlab.ui.control.CheckBox
        CheckBox_14                  matlab.ui.control.CheckBox
        CheckBox_6                   matlab.ui.control.CheckBox
        CheckBox_93                  matlab.ui.control.CheckBox
        CheckBox_85                  matlab.ui.control.CheckBox
        CheckBox_77                  matlab.ui.control.CheckBox
        CheckBox_69                  matlab.ui.control.CheckBox
        CheckBox_61                  matlab.ui.control.CheckBox
        CheckBox_53                  matlab.ui.control.CheckBox
        CheckBox_45                  matlab.ui.control.CheckBox
        CheckBox_37                  matlab.ui.control.CheckBox
        CheckBox_29                  matlab.ui.control.CheckBox
        CheckBox_21                  matlab.ui.control.CheckBox
        CheckBox_13                  matlab.ui.control.CheckBox
        CheckBox_5                   matlab.ui.control.CheckBox
        CheckBox_92                  matlab.ui.control.CheckBox
        CheckBox_84                  matlab.ui.control.CheckBox
        CheckBox_76                  matlab.ui.control.CheckBox
        CheckBox_68                  matlab.ui.control.CheckBox
        CheckBox_60                  matlab.ui.control.CheckBox
        CheckBox_52                  matlab.ui.control.CheckBox
        CheckBox_44                  matlab.ui.control.CheckBox
        CheckBox_36                  matlab.ui.control.CheckBox
        CheckBox_28                  matlab.ui.control.CheckBox
        CheckBox_20                  matlab.ui.control.CheckBox
        CheckBox_12                  matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_91                  matlab.ui.control.CheckBox
        CheckBox_83                  matlab.ui.control.CheckBox
        CheckBox_75                  matlab.ui.control.CheckBox
        CheckBox_67                  matlab.ui.control.CheckBox
        CheckBox_59                  matlab.ui.control.CheckBox
        CheckBox_51                  matlab.ui.control.CheckBox
        CheckBox_43                  matlab.ui.control.CheckBox
        CheckBox_35                  matlab.ui.control.CheckBox
        CheckBox_27                  matlab.ui.control.CheckBox
        CheckBox_19                  matlab.ui.control.CheckBox
        CheckBox_11                  matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_90                  matlab.ui.control.CheckBox
        CheckBox_82                  matlab.ui.control.CheckBox
        CheckBox_74                  matlab.ui.control.CheckBox
        CheckBox_66                  matlab.ui.control.CheckBox
        CheckBox_58                  matlab.ui.control.CheckBox
        CheckBox_50                  matlab.ui.control.CheckBox
        CheckBox_42                  matlab.ui.control.CheckBox
        CheckBox_34                  matlab.ui.control.CheckBox
        CheckBox_26                  matlab.ui.control.CheckBox
        CheckBox_18                  matlab.ui.control.CheckBox
        CheckBox_10                  matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_89                  matlab.ui.control.CheckBox
        CheckBox_81                  matlab.ui.control.CheckBox
        CheckBox_73                  matlab.ui.control.CheckBox
        CheckBox_65                  matlab.ui.control.CheckBox
        CheckBox_57                  matlab.ui.control.CheckBox
        CheckBox_49                  matlab.ui.control.CheckBox
        CheckBox_41                  matlab.ui.control.CheckBox
        CheckBox_33                  matlab.ui.control.CheckBox
        CheckBox_25                  matlab.ui.control.CheckBox
        CheckBox_17                  matlab.ui.control.CheckBox
        CheckBox_9                   matlab.ui.control.CheckBox
        CheckBox_1                   matlab.ui.control.CheckBox
        GoodnessOfFitPanel           matlab.ui.container.Panel
        Rsquare                      matlab.ui.control.NumericEditField
        RsquareLabel                 matlab.ui.control.Label
        DynamicsSelectionPanel       matlab.ui.container.Panel
        LastDynamicEditField         matlab.ui.control.NumericEditField
        LastDynamicEditFieldLabel    matlab.ui.control.Label
        FirstDynamicEditField        matlab.ui.control.NumericEditField
        FirstDynamicEditFieldLabel   matlab.ui.control.Label
        SliceSelectionPanel          matlab.ui.container.Panel
        LastSliceEditField           matlab.ui.control.NumericEditField
        LastSliceEditFieldLabel      matlab.ui.control.Label
        FirstSliceEditField          matlab.ui.control.NumericEditField
        FirstSliceEditFieldLabel     matlab.ui.control.Label
        AppSettingsTab               matlab.ui.container.Tab
        ImportSettingsPanel          matlab.ui.container.Panel
        ToggleReorderCheckBox        matlab.ui.control.CheckBox
        ToggleReorderCheckBoxLabel   matlab.ui.control.Label
        SoundsPanel                  matlab.ui.container.Panel
        AutoSoundsCheckBox           matlab.ui.control.CheckBox
        AutoSoundsCheckBoxLabel      matlab.ui.control.Label
        ColorsPanel                  matlab.ui.container.Panel
        ColorsButtonGroup            matlab.ui.container.ButtonGroup
        BlackWhiteButton             matlab.ui.control.RadioButton
        ClassicButton                matlab.ui.control.RadioButton
        TabGroup1                    matlab.ui.container.TabGroup
        DashboardTab                 matlab.ui.container.Tab
        ExitButton                   matlab.ui.control.Button
        Lamp7                        matlab.ui.control.Lamp
        ExportGIFButton              matlab.ui.control.Button
        Lamp6                        matlab.ui.control.Lamp
        ExportDCMButton              matlab.ui.control.Button
        AbortFitButton               matlab.ui.control.Button
        EstimatedFitTimeViewField    matlab.ui.control.EditField
        ModelBasedCheckBox           matlab.ui.control.CheckBox
        ModelBasedCheckBoxLabel      matlab.ui.control.Label
        Lamp5                        matlab.ui.control.Lamp
        FitT1Button                  matlab.ui.control.Button
        FitProgressGauge             matlab.ui.control.LinearGauge
        FitProgressLabel             matlab.ui.control.Label
        Lamp4                        matlab.ui.control.Lamp
        MaskButton                   matlab.ui.control.Button
        AbortRegButton               matlab.ui.control.Button
        EstimatedRegTimeViewField    matlab.ui.control.EditField
        RegistrationDropDown         matlab.ui.control.DropDown
        Lamp3                        matlab.ui.control.Lamp
        RegisterButton               matlab.ui.control.Button
        RegProgressGauge             matlab.ui.control.LinearGauge
        RegProgressLabel             matlab.ui.control.Label
        CSrecoCheckBox               matlab.ui.control.CheckBox
        CSrecoCheckBoxLabel          matlab.ui.control.Label
        Lamp2                        matlab.ui.control.Lamp
        RecoImagesButton             matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        LoadMRDfileButton            matlab.ui.control.Button
        TulpLogo                     matlab.ui.control.Image
        Tsub1subMappingLabel         matlab.ui.control.Label
        MRSImage                     matlab.ui.control.Image
    end


    % --------------------------------------------------------------------------------------
    %
    %   T1mapp
    %
    %   T1 mapping of MR Solutions data, by inversion recovery, saturation recovery
    %   or variable flip angle
    %
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   g.j.strijkers@amsterdamumc.nl
    %   September 2026
    %
    %   How the app is put together:
    %
    %     T1mapp.mlapp + gui/   widgets, callbacks and plotting; gui/adapters holds
    %                           the only code that reads a widget or holds the app
    %     @t1Data               the dataset the app holds as app.td: k-space,
    %                           images, maps, and the import that fills them
    %     +t1mapp/              import and export, reconstruction and registration,
    %                           fitting, geometry and colour helpers
    %     functions/            third party only
    %
    %   The three methods share one image layout, (frame, x, y, slice, dynamic).
    %   The raw image viewer always has a slice, a frame and a dynamic slider; the
    %   frame slider runs through the TIs or TRs of a recovery, or the cardiac
    %   frames of a flip angle scan, and the dynamic slider through the repetitions
    %   or the flip angles. The maps are indexed by whichever of the two was not
    %   fitted. ApplyMethodLabelsFcn names the sliders accordingly.
    %
    % --------------------------------------------------------------------------------------


    properties (Access = public)

        % App version
        appVersion = "1.0.2"        % App version
        bartVersion                 % BART version

        % The dataset
        td                          % t1Data, the data this session works on

        % Folders
        appFolder                   % application folder
        oldFolder                   % folder MATLAB was in when the app started
        oldWarningState             % warning state MATLAB had when the app started
        busyFlag = false            % a step is running: status BUSY until OK or ERROR
        closeRequested = false      % the window was closed while a step was running
        closeTimer                  % timer that closes the app once that step has finished
        mrdImportFolder = ''        % MRD import folder
        dicomExportFolder = ''      % DICOM export folder
        gifExportFolder = ''        % GIF export folder
        roiExportFolder = ''        % ROI data export folder
        exportFolder = ''           % folder the last DICOM export was written to

        % Flags
        GPUpresentFlag = false      % BART compiled with GPU support
        bartDetectedFlag = false    % BART toolbox available
        elastixDetectedFlag = false % elastix can be run
        validDataFlag = false       % a valid dataset is loaded
        validRecoFlag = false       % images have been reconstructed
        validRegFlag = false        % the images have been registered
        validFitFlag = false        % maps have been fitted
        mrdFlag = true              % the scanner's MRD folder has not been looked up yet
        roiFlag = false             % ROI drawing rather than cross-hair
        abortRegFlag = false        % abort the registration
        abortFitFlag = false        % abort the fit

        % Plots
        txt1                        % R-square colour bar label
        txt2                        % R-square colour bar label
        crossT1                     % cross-hair on the T1 map
        crossM0                     % cross-hair on the M0 map
        roiPoints = []              % ROI point coordinates (x, y, slice)
        roiT1                       % ROI drawing on the T1 map
        roiM0                       % ROI drawing on the M0 map
        roiIntDataSigned = []       % ROI intensities with the sign the fit restored
        maskBeforeOperation = []    % the mask before the last erode, dilate, close or open, for Undo
        maskAfterOperation = []     % the mask that operation left, so Undo can tell it is current

        % Colour maps
        t1cmap                      % T1 colour map
        m0cmap                      % M0 colour map
        r2cmap                      % R-square colour map

        % Colours
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey
        lightblue                   % light blue
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound

        % Settings and log
        jsonPar = struct()          % settings, from t1mapp.io.readSettingsFile
        logFile = -1                % file id of this session's log, -1 when there is none
        logFileName = ''            % full path of this session's log

    end


    methods (Access = private)


        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)

            % Purpose:
            %   Colours the seven lamps beside the dashboard buttons.
            %
            % How it works:
            %   0 is red, 1 is green, 3 is yellow for the step that is running,
            %   anything else leaves the lamp as it is.

            lamps = {app.Lamp1, app.Lamp2, app.Lamp3, app.Lamp4, app.Lamp5, app.Lamp6, app.Lamp7};

            for k = 1:numel(lamps)
                if code(k) == 0
                    lamps{k}.Color = app.red;
                elseif code(k) == 1
                    lamps{k}.Color = app.green;
                elseif code(k) == 3
                    lamps{k}.Color = app.yellow;
                end
            end

            drawnow;

        end


        % Buttons on/off
        function SetButtons(app,code)

            % Purpose:
            %   Enables or disables the eight dashboard buttons.
            %
            % How it works:
            %   One entry per button, in dashboard order: load, reconstruct,
            %   register, mask, fit, DICOM export, GIF export, exit.

            app.SetEnableFcn({app.LoadMRDfileButton}, code(1) ~= 0);
            app.SetEnableFcn({app.RecoImagesButton}, code(2) ~= 0);
            app.SetEnableFcn({app.RegisterButton, app.RegistrationDropDown}, code(3) ~= 0);
            app.SetEnableFcn({app.MaskButton}, code(4) ~= 0);
            app.SetEnableFcn({app.FitT1Button}, code(5) ~= 0);
            app.SetEnableFcn({app.ExportDCMButton}, code(6) ~= 0);
            app.SetEnableFcn({app.ExportGIFButton}, code(7) ~= 0);
            app.SetEnableFcn({app.ExitButton}, code(8) ~= 0);

            drawnow;

        end


        % Enable or disable a set of widgets
        function SetEnableFcn(app, widgets, tf) %#ok<INUSD>

            % Purpose:
            %   Sets Enable on every widget in the list to the same state.

            state = 'off';
            if tf
                state = 'on';
            end

            for k = 1:numel(widgets)
                widgets{k}.Enable = state;
            end

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            % Purpose:
            %   Applies one of the two colour schemes to every panel, label and axis.
            %
            % How it works:
            %   guiColors is backgroundColor, fontColor, boxColor, gaugeColor,
            %   gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, and two
            %   colours this app does not use.

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};

            % Panels
            panels = {app.T1mappUIFigure, app.DashboardTab, app.RawImagesTab, app.RecoParametersTab, ...
                app.FitParametersTab, app.AppSettingsTab, app.SequenceParametersTab, app.T1mapTab, ...
                app.M0R2mapTab, app.ImageSelectionPanel, app.MaskSettingsPanel, app.SliceSelectionPanel, ...
                app.DynamicsSelectionPanel, app.GoodnessOfFitPanel, app.InversionTimeSelectionPanel, ...
                app.ColorsButtonGroup, app.T1ImageSelectionPanel, app.T1ColormapButtonGroup, ...
                app.M0ImageSelectionPanel, app.M0ColorMapButtonGroup, app.R2ColorMapButtonGroup, ...
                app.CSregularizationPanel, app.FilteringPanel, app.ColorsPanel, app.SoundsPanel, ...
                app.ImportSettingsPanel};

            app.T1mappUIFigure.Color = backgroundColor;
            for k = 2:numel(panels)
                panels{k}.BackgroundColor = backgroundColor;
            end

            % Gauges
            app.RegProgressGauge.FontColor = gaugeColor;
            app.RegProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Texts
            texts = {app.CSrecoCheckBoxLabel, app.ModelBasedCheckBoxLabel, app.SlicesEditField1Label, ...
                app.InversionEditFieldLabel, app.DynamicsEditField1Label, app.thresholdEditFieldLabel, ...
                app.WindowEditFieldLabel, app.LevelEditFieldLabel, app.DeNoiseCheckBoxLabel, ...
                app.DeRingCheckBoxLabel, app.WVxyzEditFieldLabel, app.TVxyzEditFieldLabel, ...
                app.LLRxyzEditFieldLabel, app.TVtiEditFieldLabel, app.TVdynEditFieldLabel, ...
                app.FirstSliceEditFieldLabel, app.LastSliceEditFieldLabel, app.FirstDynamicEditFieldLabel, ...
                app.LastDynamicEditFieldLabel, app.RsquareLabel, app.AutoSoundsCheckBoxLabel, ...
                app.ToggleReorderCheckBoxLabel, app.BlackWhiteButton, app.ClassicButton, ...
                app.DatafileEditFieldLabel, app.TR0ViewFieldLabel, app.TRsEditFieldLabel, ...
                app.NrTRsViewFieldLabel, app.TI0EditFieldLabel, app.TIsEditFieldLabel, ...
                app.NrTIsViewFieldLabel, app.NrDynamicsViewFieldLabel, app.AspectRatioViewFieldLabel, ...
                app.FOVViewFieldLabel, app.dimxViewFieldLabel, app.dimyViewFieldLabel, ...
                app.SlicesViewFieldLabel, app.SLTViewFieldLabel, app.SlicesEditField2Label, ...
                app.DynamicsEditField2Label, app.T1ScaleMaxLabel, app.T1ScaleMinLabel, app.grayButton, app.autumnButton, ...
                app.winterButton, app.parulaButton, app.lipariButton, app.BrushEditFieldLabel, ...
                app.XEditFieldLabel, app.YEditFieldLabel, app.T1ViewField1Label, app.T1ViewField2Label, ...
                app.T1PixelValueLabel, app.T1ROIAveragedLabel, app.ImageLabel, app.T1MapLabel, ...
                app.M0MapLabel, app.R2MapLabel, app.SlicesEditField3Label, app.DynamicsEditField3Label, ...
                app.grayButton2, app.autumnButton2, app.winterButton2, app.parulaButton2, app.lipariButton2, ...
                app.BrushEditField2Label, app.grayButton3, app.autumnButton3, app.winterButton3, ...
                app.parulaButton3, app.lipariButton3};

            for k = 1:numel(texts)
                texts{k}.FontColor = fontColor;
            end

            % Selection check boxes
            for k = 1:96
                app.("CheckBox_" + k).FontColor = fontColor;
            end

            % Images and figures
            axesList = {app.RawDataFig, app.T1MapFig, app.M0MapFig, app.R2MapFig};
            for k = 1:numel(axesList)
                axesList{k}.Color = movieFigureColor;
                axesList{k}.XColor = movieFigureColor;
                axesList{k}.YColor = movieFigureColor;
            end

            app.T1linearPlot.Color = figureColor;
            app.T1linearPlot.XColor = fontColor;
            app.T1linearPlot.YColor = fontColor;

        end % SetAppColorsFcn


        % Check for a newer version
        function CheckVersionFnc(app)

            % Purpose:
            %   Tells the user when a newer release is published.
            %
            % How it works:
            %   Each release of the app lives in a repository of its own,
            %   T1mapping-v<major.minor>, whose appversion.txt holds
            %   T1mapp_version=x.y.z. The raw file is read, newest repository first,
            %   and the first that answers is compared with this version. Version 1.0.1
            %   read a repository named T1mapp, which does not exist, so every start
            %   reported that the version could not be checked.
            %
            %   A repository that does not exist answers at once with an HTTP status, and
            %   the next one is worth trying. Any other failure -- no network, a timeout,
            %   a proxy refusing the connection -- would fail the same way for every
            %   repository, each after the full timeout, so the search stops there, as
            %   in ADCmapp and DSCmapp.
            %
            %   Any failure is reported once and is not an error. Whether to look, and how
            %   long to wait, are settings.

            if ~app.jsonPar.checkForUpdates
                app.TextMessage('Version check switched off in the settings ...');
                return
            end

            repositories = ["T1mapping-v1.3", "T1mapping-v1.2", "T1mapping-v1.1", "T1mapping-v1.0"];
            opts = weboptions('Timeout', app.jsonPar.versionCheckTimeout, 'ContentType', 'text', 'CharacterEncoding', 'UTF-8');
            tok = {};

            for repository = repositories
                try
                    webTxt = strtrim(string(webread("https://raw.githubusercontent.com/Moby1971/" + repository + "/main/appversion.txt", opts)));
                    tok = regexp(webTxt, 'T1mapp_version\s*=\s*([0-9]+(?:\.[0-9]+){1,3})', 'tokens', 'once', 'ignorecase');
                catch webME
                    tok = {};
                    if ~contains(webME.identifier, 'StatusCodeError')
                        break
                    end
                end
                if ~isempty(tok)
                    break
                end
            end

            if isempty(tok)
                app.TextMessage("Could not check the T1 mapping app version ...");
                return
            end

            newVersion = sscanf(char(tok{1}), '%d.%d.%d').';
            current = sscanf(char(app.appVersion), '%d.%d.%d').';
            newVersion(end+1:3) = 0;
            current(end+1:3) = 0;

            app.TextMessage("Current version = " + app.appVersion);
            app.TextMessage("Newest version = " + string(tok{1}));

            difference = find(newVersion ~= current, 1);
            if ~isempty(difference) && newVersion(difference) > current(difference)
                app.TextMessage("A new version of the T1 mapping app is available ...");
                app.TextMessage("Visit https://github.com/Moby1971/ to update ...");
            else
                app.TextMessage("The T1 mapping app is up to date ...");
            end

        end % CheckVersionFnc


        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)

            % Purpose:
            %   Enables the fit and reconstruction settings the loaded dataset allows.
            %
            % How it works:
            %   Code 0 disables everything. Otherwise a slice or map range is only
            %   offered when there is more than one, compressed sensing only with BART,
            %   and the model-based fit only for a 2D inversion recovery with BART whose
            %   images have not been registered: it needs the measured k-space, and
            %   registered magnitude images have lost the sign of the recovery.
            %   The model-based box is cleared as well
            %   as greyed out when it cannot be used, so a setting from a previous
            %   dataset cannot carry over. One point selection box per point of the
            %   series is enabled.

            on = code ~= 0;

            app.SetEnableFcn({app.FirstSliceEditField, app.FirstSliceEditFieldLabel, ...
                app.LastSliceEditField, app.LastSliceEditFieldLabel}, on && app.td.ns > 1);

            app.SetEnableFcn({app.FirstDynamicEditField, app.FirstDynamicEditFieldLabel, ...
                app.LastDynamicEditField, app.LastDynamicEditFieldLabel}, on && app.td.nMaps > 1);

            app.SetEnableFcn({app.Rsquare, app.RsquareLabel}, on);

            app.SetEnableFcn({app.CSrecoCheckBox, app.CSrecoCheckBoxLabel, app.WVxyzEditField, ...
                app.WVxyzEditFieldLabel, app.TVxyzEditField, app.TVxyzEditFieldLabel, app.LLRxyzEditField, ...
                app.LLRxyzEditFieldLabel, app.TVtiEditField, app.TVtiEditFieldLabel, app.TVdynEditField, ...
                app.TVdynEditFieldLabel}, on && app.bartDetectedFlag && app.validDataFlag);

            app.SetEnableFcn({app.DeNoiseCheckBox, app.DeNoiseCheckBoxLabel, ...
                app.DeRingCheckBox, app.DeRingCheckBoxLabel}, on);

            % The model-based box is cleared only when the dataset does not allow it,
            % not while the app is busy: FitT1ButtonPushed calls this with 0 before it
            % reads the box, which cleared it, so the model-based fit never ran from the
            % Fit button (found by the Python port, 2026-09-30)
            mobaPossible = app.bartDetectedFlag && app.validRecoFlag && ~app.validRegFlag && ...
                app.td.method == t1mapp.Constants.METHOD_IR && ~app.td.data3dFlag;
            app.SetEnableFcn({app.ModelBasedCheckBox, app.ModelBasedCheckBoxLabel}, on && mobaPossible);
            if ~mobaPossible
                app.ModelBasedCheckBox.Value = 0;
            end

            % The inversion times, off while a fit or registration runs: an edit then
            % changed them for the slices still to fit (2026-09-30)
            app.SetEnableFcn({app.TI0EditField, app.TI0EditFieldLabel, app.TIsEditField, ...
                app.TIsEditFieldLabel}, on);

            for k = 1:96
                app.("CheckBox_" + k).Enable = matlab.lang.OnOffSwitchState(on && k <= app.td.nPoints);
            end

            drawnow;

        end


        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)

            % Purpose:
            %   Enables the raw image sliders the dataset has more than one position
            %   for, and the window and level controls.

            on = code ~= 0;

            app.SetEnableFcn({app.SlicesEditField1, app.SlicesEditField1Label, app.SlicesSlider1}, on && app.td.ns > 1);
            app.SetEnableFcn({app.InversionEditField, app.InversionEditFieldLabel, app.InversionSlider}, on && app.td.nf > 1);
            app.SetEnableFcn({app.DynamicsEditField1, app.DynamicsEditField1Label, app.DynamicsSlider1}, on && app.td.nd > 1);
            app.SetEnableFcn({app.WindowEditField, app.WindowEditFieldLabel, app.WindowSlider, ...
                app.LevelEditField, app.LevelEditFieldLabel, app.LevelSlider, app.AutoLWButton}, on);

            drawnow;

        end


        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)

            % Purpose:
            %   Enables the threshold, the mask operations and their size, and Undo when
            %   there is an operation to undo.

            app.SetEnableFcn({app.thresholdEditField, app.thresholdEditFieldLabel, app.ThresholdSlider, ...
                app.ErodeButton, app.DilateButton, app.CloseButton, app.OpenButton, ...
                app.MaskSizeEditField}, code ~= 0);
            app.SetEnableFcn({app.UndoButton}, code ~= 0 && ~isempty(app.maskAfterOperation));

            drawnow;

        end


        % Change the mask by a morphological operation
        function MaskOperationFcn(app, operation)

            % Purpose:
            %   Erodes, dilates, closes or opens the masked region, the one drawn in red,
            %   in every slice and every map.
            %
            % How it works:
            %   The mask is 1 where a pixel is fitted and the red overlay shows where it
            %   is 0, so the operation is applied to the background, 1 - mask: Erode
            %   shrinks the red region, Dilate grows it, Close fills gaps in it and Open
            %   removes small red specks. Multi-slice data is changed slice by slice and
            %   3D data volume by volume, with the element from MaskElementFcn.
            %
            %   The mask before and after is kept for Undo, as logical arrays. A failure
            %   halfway puts the mask back as it was.

            if ~app.validDataFlag
                return
            end

            previousMask = app.td.mask;

            try

                element = app.MaskElementFcn;

                for map = 1:size(app.td.mask, 4)
                    if app.td.data3dFlag
                        background = 1 - app.td.mask(:,:,:,map);
                        app.td.mask(:,:,:,map) = 1 - app.MorphologyFcn(background, operation, element);
                    else
                        for slice = 1:size(app.td.mask, 3)
                            background = 1 - app.td.mask(:,:,slice,map);
                            app.td.mask(:,:,slice,map) = 1 - app.MorphologyFcn(background, operation, element);
                        end
                    end
                end

                app.maskBeforeOperation = previousMask == 1;
                app.maskAfterOperation = app.td.mask == 1;
                app.UndoButton.Enable = 'on';
                app.PlotImageFig;

            catch ME

                app.td.mask = previousMask;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end % MaskOperationFcn


        % The structuring element of the mask operations
        function element = MaskElementFcn(app)

            % Purpose:
            %   Makes the element Erode, Dilate, Close and Open use, from the size field
            %   and the pixel size along each axis.
            %
            % How it works:
            %   The size is a radius in the finest in-plane pixels. t1mapp.util.pixelSize
            %   gives the pixel size along each axis of the mask as the DICOM export
            %   computes it, and t1mapp.util.maskElement makes an element that is round in
            %   millimetres: a disk or an ellipse for multi-slice data, and for 3D data an
            %   ellipsoid that crosses the partitions as far as their thickness allows.
            %   Square pixels on multi-slice data give the exact disk used before.

            pixelSizes = t1mapp.util.pixelSize(app.td);
            element = t1mapp.util.maskElement(app.MaskSizeEditField.Value, pixelSizes, app.td.data3dFlag);

        end % MaskElementFcn


        % One morphological operation on a background image or volume
        function background = MorphologyFcn(app, background, operation, element) %#ok<INUSL>

            % Purpose:
            %   Erodes, dilates, closes or opens the background with the element.

            switch operation
                case "erode"
                    background = imerode(background, element);
                case "dilate"
                    background = imdilate(background, element);
                case "close"
                    background = imclose(background, element);
                case "open"
                    background = imopen(background, element);
            end

        end % MorphologyFcn


        % Switch map buttons on/off
        function SetMapButtons(app,code)

            % Purpose:
            %   Enables the map sliders, the scale, the colour maps and the ROI tools.
            %
            % How it works:
            %   The map sliders are offered when there is more than one slice, and
            %   more than one map along the axis that was not fitted.

            on = code ~= 0;

            app.SetEnableFcn({app.SlicesEditField2, app.SlicesEditField2Label, app.SlicesSlider2, ...
                app.SlicesEditField3, app.SlicesEditField3Label, app.SlicesSlider3}, on && app.td.ns > 1);

            app.SetEnableFcn({app.DynamicsEditField2, app.DynamicsEditField2Label, app.DynamicsSlider2, ...
                app.DynamicsEditField3, app.DynamicsEditField3Label, app.DynamicsSlider3}, on && app.td.nMaps > 1);

            app.SetEnableFcn({app.T1ScaleEditField, app.T1ScaleMaxLabel, app.T1ScaleMinEditField, ...
                app.T1ScaleMinLabel, app.T1ScaleAutoButton, ...
                app.grayButton, app.autumnButton, app.winterButton, app.parulaButton, app.lipariButton, ...
                app.EraseButton, app.ROIbutton, app.BrushEditField, app.BrushEditFieldLabel, ...
                app.ExportRoiDataButton, app.grayButton2, app.autumnButton2, app.winterButton2, ...
                app.parulaButton2, app.lipariButton2, app.EraseButton2, app.ROIbutton2, app.BrushEditField2, ...
                app.BrushEditField2Label, app.grayButton3, app.autumnButton3, app.winterButton3, ...
                app.parulaButton3, app.lipariButton3}, on);

            drawnow;

        end


        % Initialize the GUI
        function InitializeUI(app)

            % Purpose:
            %   Puts the interface in its starting state: colours, icons, sounds,
            %   plot settings, colour maps and the parameter fields.
            %
            % How it works:
            %   The reconstruction and fit fields start from the shipped defaults in
            %   +t1mapp, so the values a script gets and the values the app starts with
            %   are the same numbers.

            app.TextMessage('Initializing UI ...');

            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green';
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878';
            app.lightblue = '#DEE6EB';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.85,0.85,0.85];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];

            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';

            % Sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoImagesButton.Icon = 'reconstruct.gif';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitT1Button.Icon = 'exponential.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T1Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';

            % Lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.AbortFitButton.Enable = 'off';
            app.AbortRegButton.Enable = 'off';

            % Plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T1MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.R2MapFig.Toolbar.Visible = 'off';
            app.T1linearPlot.Toolbar.Visible = 'off';
            app.RawDataFig.Interactions = [];
            app.T1MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.R2MapFig.Interactions = [];
            app.T1linearPlot.Interactions = [];

            % Colour maps, sounds, colour scheme and import option, from the settings
            app.(app.jsonPar.display.t1ColorMap + "Button").Value = 1;
            app.(app.jsonPar.display.m0ColorMap + "Button2").Value = 1;
            app.(app.jsonPar.display.r2ColorMap + "Button3").Value = 1;
            app.t1cmap = app.ColorMapFromButtonsFcn(1, gray);
            app.m0cmap = app.ColorMapFromButtonsFcn(2, gray);
            app.r2cmap = app.ColorMapFromButtonsFcn(3, gray);
            app.AutoSoundsCheckBox.Value = logical(app.jsonPar.sounds);
            if strcmp(app.jsonPar.colorScheme, 'classic')
                app.ClassicButton.Value = true;
            else
                app.BlackWhiteButton.Value = true;
            end
            app.ToggleReorderCheckBox.Value = logical(app.jsonPar.import.toggleSegmentOrder);

            % Parameter fields from the settings, whose own defaults are the
            % shipped parameter defaults
            recoDefaults = app.jsonPar.reconstruction;
            app.WVxyzEditField.Value = recoDefaults.wavelet;
            app.TVxyzEditField.Value = recoDefaults.tvSpatial;
            app.LLRxyzEditField.Value = recoDefaults.lowRank;
            app.TVtiEditField.Value = recoDefaults.tvFrame;
            app.TVdynEditField.Value = recoDefaults.tvDynamic;
            app.DeNoiseCheckBox.Value = recoDefaults.denoise;
            app.DeRingCheckBox.Value = recoDefaults.deRing;

            fitDefaults = app.jsonPar.fitting;
            app.Rsquare.Value = fitDefaults.rSquare;

            regDefaults = app.jsonPar.registration;
            app.RegistrationDropDown.Value = regDefaults.method;

            displayDefaults = app.jsonPar.display;
            app.T1ScaleEditField.Value = displayDefaults.t1Scale;
            app.T1ScaleMinEditField.Value = displayDefaults.t1ScaleMin;

            % ROIs and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT1);
            delete(app.roiM0);

            app.SetStatus(3);

            drawnow;

        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % The map the raw image viewer is showing the mask of
        function index = RawMapIndexFcn(app)

            % Purpose:
            %   Gives the map index that belongs to the image on screen.
            %
            % How it works:
            %   The maps and masks are indexed by the axis that is not fitted: the
            %   dynamic slider for a recovery, the frame slider for a flip angle scan.

            if app.td.fitDim == 5
                index = app.InversionEditField.Value;
            else
                index = app.DynamicsEditField1.Value;
            end

            index = min(max(1, round(index)), max(1, app.td.nMaps));

        end


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            % Purpose:
            %   Draws the selected image with the mask overlaid in red.
            %
            % How it works:
            %   Since one axes cannot hold two colour maps, the map is split in a grey
            %   half [0 1] for the image and a red half [1 2] for the mask overlay.

            if ~app.validRecoFlag
                return
            end

            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');

            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;

            slice = app.SlicesEditField1.Value;
            frame = app.InversionEditField.Value;
            dynamic = app.DynamicsEditField1.Value;
            mapIndex = app.RawMapIndexFcn;
            phaseOrientation = app.td.parameters.PHASE_ORIENTATION;

            % Background anatomy image
            im = reshape(app.td.images(frame,:,:,slice,dynamic), app.td.dimx, app.td.dimy);
            if phaseOrientation
                im = rot90(im,-1);
            end

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                if ~(maxIm > 0)
                    maxIm = 1;
                end
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            xlim(app.RawDataFig,[0, size(im,1) + 1]);
            ylim(app.RawDataFig,[0, size(im,2) + 1]);
            imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);

            % Overlay with the mask
            imo = 2 - app.td.mask(:,:,slice,mapIndex);
            alpha = 0.7 - 0.7*app.td.mask(:,:,slice,mapIndex);
            if phaseOrientation
                imo = rot90(imo,-1);
                alpha = rot90(alpha,-1);
            end
            overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
            set(overlay,'AlphaData',rot90(alpha));

            % Image aspect ratio
            if phaseOrientation
                aratio = app.td.aspectRatio*size(im,2)/size(im,1);
                daspect(app.RawDataFig,[1 aratio 1]);
            else
                aratio = app.td.aspectRatio*size(im,1)/size(im,2);
                daspect(app.RawDataFig,[aratio 1 1]);
            end

            hold(app.RawDataFig,'off');
            drawnow;

            % Threshold limits and the value for this slice and map
            maxim = max(app.td.images(:));
            app.thresholdEditField.Limits = [0 maxim+1];
            app.ThresholdSlider.Limits = [0 maxim+1];
            thr = min(max(app.td.threshold(slice,mapIndex),0),maxim);
            app.thresholdEditField.Value = round(thr);
            app.ThresholdSlider.Value = thr;

        end % PlotImageFig


        % Plots the maps for the current slice
        function PlotMaps(app)

            % Purpose:
            %   Draws the T1, M0 and R-square maps of the selected slice and map.
            %
            % How it works:
            %   T1 is shown from the Min to the Max scale field, M0 up to the 99.5th
            %   percentile of its map, and
            %   R-square from the rejection threshold to 1. An ROI being drawn is
            %   redrawn and its fit updated.

            if ~app.validFitFlag
                return
            end

            slice = app.SlicesEditField2.Value;
            mapIndex = min(app.DynamicsEditField2.Value, size(app.td.t1map,4));
            phaseOrientation = app.td.parameters.PHASE_ORIENTATION;

            hold(app.T1MapFig,'on');
            hold(app.M0MapFig,'on');
            hold(app.R2MapFig,'on');

            % A redraw replaces the previous images: with hold on, imshow would
            % otherwise stack a new copy on the axes at every redraw
            delete(findobj([app.T1MapFig, app.M0MapFig, app.R2MapFig],'Type','image'));

            % T1 map
            im = app.td.t1map(:,:,slice,mapIndex);
            if phaseOrientation
                im = rot90(im,-1);
            end
            if phaseOrientation
                aratio = app.td.aspectRatio*size(im,2)/size(im,1);
                aspect = [1 aratio 1];
            else
                aratio = app.td.aspectRatio*size(im,1)/size(im,2);
                aspect = [aratio 1 1];
            end
            xlim(app.T1MapFig,[0, size(im,1) + 1]);
            ylim(app.T1MapFig,[0, size(im,2) + 1]);
            imshow(rot90(im),[app.T1ScaleMinEditField.Value app.T1ScaleEditField.Value],'Colormap',app.t1cmap,'InitialMagnification','fit','Parent',app.T1MapFig);
            daspect(app.T1MapFig,aspect);
            colorbar(app.T1MapFig,'east','Ticks',[]);

            % M0 map, up to a high percentile of the whole map rather than twice
            % the slice mean: a few outlying voxels would otherwise set the scale,
            % and one scale for all slices keeps them comparable
            im = app.td.m0map(:,:,slice,mapIndex);
            m0scale = t1mapp.util.robustDisplayMax(app.td.m0map, t1mapp.Constants.M0_DISPLAY_PERCENTILE);
            if phaseOrientation
                im = rot90(im,-1);
            end
            xlim(app.M0MapFig,[0, size(im,1) + 1]);
            ylim(app.M0MapFig,[0, size(im,2) + 1]);
            imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
            daspect(app.M0MapFig,aspect);

            % R-square map
            im = app.td.r2map(:,:,slice,mapIndex);
            if phaseOrientation
                im = rot90(im,-1);
            end
            low = min(app.Rsquare.Value, 0.98) - 0.01;
            xlim(app.R2MapFig,[0, size(im,1) + 1]);
            ylim(app.R2MapFig,[0, size(im,2) + 1]);
            imshow(rot90(im),[low 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
            daspect(app.R2MapFig,aspect);
            colorbar(app.R2MapFig,'east','Ticks',[low, 1]);
            delete(app.txt1);
            delete(app.txt2);
            app.txt1 = text(app.R2MapFig,0.8,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
            app.txt2 = text(app.R2MapFig,0.8,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

            % ROI or cross-hair display if present, after the M0 map so its image does
            % not cover the markers drawn there
            if app.roiFlag && ~isempty(app.roiPoints)
                app.roiPoints = unique(app.roiPoints,'rows');
                plotPoints = app.roiPoints(app.roiPoints(:,3)==slice, :);
                app.PlotPoint(plotPoints(:,1),plotPoints(:,2),app.MapYSizeFcn);
                app.CalculateRoiT1;
            elseif ~app.roiFlag && size(app.roiPoints,1) == 1
                % the cross-hair only on the slice it was placed on
                if app.roiPoints(3) == slice
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),app.MapYSizeFcn);
                else
                    delete(app.crossT1);
                    delete(app.crossM0);
                end
                app.CalculateRoiT1;
            end

            hold(app.T1MapFig,'off');
            hold(app.M0MapFig,'off');
            hold(app.R2MapFig,'off');
            drawnow;

        end % PlotMaps


        % The number of rows a map is displayed with
        function ysize = MapYSizeFcn(app)

            % Purpose:
            %   Gives the vertical extent of a displayed map, which the click and ROI
            %   coordinates are measured against.

            if app.td.parameters.PHASE_ORIENTATION
                ysize = app.td.dimx;
            else
                ysize = app.td.dimy;
            end

        end


        % Draw a cross-hair on the maps
        function PlotCross(app,xcor,ycor,ysize)

            if app.validFitFlag

                delete(app.crossT1);
                delete(app.crossM0);

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.lipariButton2.Value == 1
                    clr2 = 'g';
                end

                app.crossT1 = drawcrosshair(app.T1MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
                app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);

            end

        end % PlotCross


        % Draw points on the maps
        function PlotPoint(app,xcor,ycor,ysize)

            if app.validFitFlag

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.lipariButton2.Value == 1
                    clr2 = 'g';
                end

                hold(app.T1MapFig,'on');
                delete(app.roiT1);
                app.roiT1 = plot(app.T1MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
                hold(app.T1MapFig,'off');

                hold(app.M0MapFig,'on');
                delete(app.roiM0);
                app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
                hold(app.M0MapFig,'off');

            end

        end % PlotPoint


        % Calculate and show T1 values of ROI
        function CalculateRoiT1(app)

            % Purpose:
            %   Shows the T1 of the pixel or ROI under the cursor, and fits the
            %   ROI-averaged series so the fit can be drawn through the data.
            %
            % How it works:
            %   Only the points inside the mask count. When none is, the graph and
            %   the T1 fields are emptied: a curve fitted through background noise,
            %   with a T1 of NaN, says nothing about the tissue.
            %
            %   The pixel value is the mean of R1 over the fitted points, inverted,
            %   with the spread propagated; a point inside the mask that the fit
            %   rejected has no T1 to show. The series of the fitted points are
            %   averaged -- unfitted ones would only pull the curve down -- or of
            %   all points inside the mask when none was fitted, and that average is
            %   fitted with the same fitSeries the maps come from.

            if ~app.validFitFlag || isempty(app.roiPoints)
                return
            end

            try

                ysize = app.MapYSizeFcn;
                mapIndex = min(app.DynamicsEditField2.Value, size(app.td.t1map,4));
                nR = size(app.roiPoints,1);
                nP = app.td.nPoints;
                t1values = zeros(1,nR);
                inMask = false(1,nR);
                series = zeros(nR,nP);

                for i = 1:nR
                    if app.td.parameters.PHASE_ORIENTATION
                        r = ysize-app.roiPoints(i,2)+1;
                        c = app.roiPoints(i,1);
                    else
                        r = app.roiPoints(i,1);
                        c = app.roiPoints(i,2);
                    end
                    z = app.roiPoints(i,3);
                    t1values(i) = app.td.t1map(r,c,z,mapIndex);
                    inMask(i) = app.td.mask(r,c,z,mapIndex) > 0;
                    stack = t1mapp.fit.seriesOf(app.td.images, app.td.fitDim, z, mapIndex);
                    series(i,:) = stack(:,r,c)';
                end

                % Outside the mask: an empty graph and no T1
                if ~any(inMask)
                    cla(app.T1linearPlot);
                    xlim(app.T1linearPlot, [0 1]);
                    ylim(app.T1linearPlot, [0 1]);
                    app.T1ViewField1.Value = " ";
                    app.T1ViewField2.Value = " ";
                    app.td.roiIntData = [];
                    app.roiIntDataSigned = [];
                    return
                end

                fitted = inMask & t1values > 0;
                if any(fitted)
                    r1values = 1./t1values(fitted);
                    t1value = 1/mean(r1values);
                    stdt1value = t1value*std(r1values)/mean(r1values);
                    app.td.roiIntData = mean(series(fitted,:),1);
                else
                    t1value = 0;
                    stdt1value = 0;
                    app.td.roiIntData = mean(series(inMask,:),1);
                end

                % Fit of the ROI-averaged series, over the selected points
                app.MakeSelectionFcn;
                selection = app.td.pointSelection(:);
                t1roi = 0;
                model = [];
                if nnz(selection) >= t1mapp.Constants.MIN_FIT_POINTS
                    [~,t1roi,~,model] = t1mapp.fit.fitSeries(app.td.method, app.td.roiIntData(selection)', ...
                        app.td.fitValues(selection), app.td.tr);
                end

                app.PlotFits(app.td.roiIntData, t1value, t1roi, stdt1value, model);

            catch ME

                app.TextMessage(ME.message);
                app.TextMessage('Error determining T1 in ROI ...');

            end

        end % CalculateRoiT1


        % Plots the fit results
        function PlotFits(app, data, t1, t1roi, stdt1, model)

            % Purpose:
            %   Draws the ROI-averaged series and the curve fitted through it, and
            %   shows the pixel and ROI T1.
            %
            % How it works:
            %   Selected points are blue, deselected points red. The inversion
            %   recovery data is shown with the sign of the fitted curve at each TI,
            %   so the points lie on the curve the fit restored rather than on its
            %   folded magnitude.

            if ~app.validRecoFlag
                return
            end

            cla(app.T1linearPlot);
            hold(app.T1linearPlot,'on');

            values = app.td.fitValues(:)';
            cmap = [t1mapp.util.hex2rgb(app.red) ; t1mapp.util.hex2rgb(app.blue)];
            colours = cmap(double(app.td.pointSelection(:)) + 1,:);
            shown = data;

            if t1roi > 0 && ~isempty(model)

                x = linspace(0, 1.05*max(values), 400);
                s = t1mapp.fit.signalModel(app.td.method, model, x, app.td.tr);

                if app.td.method == t1mapp.Constants.METHOD_IR
                    sgn = sign(t1mapp.fit.signalModel(app.td.method, model, values, app.td.tr));
                    sgn(sgn == 0) = 1;
                    shown = data.*sgn;
                end

                plot(app.T1linearPlot, x, s, 'LineWidth', 3);
                lowest = min([0, min(s), min(shown)]);
                highest = max([max(s), max(shown)]);

                if ~(t1 > 0)
                    app.T1ViewField1.Value = " ";
                elseif stdt1 > 0
                    app.T1ViewField1.Value = [num2str(t1,'% 10.1f'),' ',char(177),' ',num2str(stdt1,'% 10.1f')];
                else
                    app.T1ViewField1.Value = num2str(t1,'% 10.1f');
                end
                app.T1ViewField2.Value = num2str(t1roi,'% 10.1f');

            else

                lowest = min([0, min(shown)]);
                highest = max(shown);
                app.T1ViewField1.Value = " ";
                app.T1ViewField2.Value = " ";

            end

            app.roiIntDataSigned = shown;
            dataPoints = scatter(app.T1linearPlot, values, shown, [], colours, 'filled');
            uistack(dataPoints, 'top');

            if ~(highest > lowest)
                highest = lowest + 1;
            end
            xlim(app.T1linearPlot, [0, 1.05*max(values)]);
            ylim(app.T1linearPlot, [lowest - 0.1*abs(highest-lowest), highest + 0.1*abs(highest-lowest)]);

            hold(app.T1linearPlot,'off');

        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets up the interface for a newly loaded dataset
        function SetParameters(app)

            % Purpose:
            %   A new dataset: name the sliders for its method, size the widgets to
            %   it, clear what the last one left behind, and show what was read.

            app.ApplyMethodLabelsFcn;
            app.SetWidgetLimitsFcn;
            app.ResetForNewDataFcn;
            app.MirrorDataOutputsFcn;

        end % SetParameters


        % Name the sliders and fields after the method
        function ApplyMethodLabelsFcn(app)

            % Purpose:
            %   Puts the method's names on the widgets whose meaning depends on it.
            %
            % How it works:
            %   The frame slider is the inversion time, the repetition time or the
            %   cardiac frame; the dynamic slider is the repetition or the flip angle;
            %   the map slider is whichever of the two was not fitted. The TI row of
            %   the sequence parameters shows flip angles for a flip angle scan, and
            %   the inversion times are editable only for an inversion recovery.

            switch app.td.method

                case t1mapp.Constants.METHOD_IR
                    frameName = "inversion";
                    dynamicName = "dynamic";
                    mapName = "dynamic";
                    seriesAxis = "TI (ms)";
                    selectionTitle = "Inversion time selection";
                    tiLabels = ["1st TI (ms)", "last TI (ms)", "#TIs"];
                    trLabels = ["TR (ms)", "last TR (ms)", "#TRs"];
                    dynamicCount = "#dynamics";
                    tvLabels = ["TVti", "TVdyn"];

                case t1mapp.Constants.METHOD_SR
                    frameName = "TR";
                    dynamicName = "dynamic";
                    mapName = "dynamic";
                    seriesAxis = "TR (ms)";
                    selectionTitle = "Repetition time selection";
                    tiLabels = ["1st TI (ms)", "last TI (ms)", "#TIs"];
                    trLabels = ["1st TR (ms)", "last TR (ms)", "#TRs"];
                    dynamicCount = "#dynamics";
                    tvLabels = ["TVtr", "TVdyn"];

                otherwise
                    frameName = "frame";
                    dynamicName = "flip angle";
                    mapName = "frame";
                    seriesAxis = "flip angle (deg)";
                    selectionTitle = "Flip angle selection";
                    tiLabels = ["1st FA (deg)", "last FA", "#FAs"];
                    trLabels = ["TR (ms)", "last TR (ms)", "#TRs"];
                    dynamicCount = "#frames";
                    tvLabels = ["TVfr", "TVfa"];

            end

            app.InversionEditFieldLabel.Text = frameName;
            app.DynamicsEditField1Label.Text = dynamicName;
            app.DynamicsEditField2Label.Text = mapName;
            app.DynamicsEditField3Label.Text = mapName;
            app.DynamicsSelectionPanel.Title = strcat(upper(extractBefore(mapName,2)), extractAfter(mapName,1), " selection");
            app.FirstDynamicEditFieldLabel.Text = "first " + mapName;
            app.LastDynamicEditFieldLabel.Text = "last " + mapName;
            app.InversionTimeSelectionPanel.Title = selectionTitle;
            app.T1linearPlot.XLabel.String = seriesAxis;
            app.TI0EditFieldLabel.Text = tiLabels(1);
            app.TIsEditFieldLabel.Text = tiLabels(2);
            app.NrTIsViewFieldLabel.Text = tiLabels(3);
            app.TR0ViewFieldLabel.Text = trLabels(1);
            app.TRsEditFieldLabel.Text = trLabels(2);
            app.NrTRsViewFieldLabel.Text = trLabels(3);
            app.NrDynamicsViewFieldLabel.Text = dynamicCount;
            app.TVtiEditFieldLabel.Text = tvLabels(1);
            app.TVdynEditFieldLabel.Text = tvLabels(2);

            editable = matlab.lang.OnOffSwitchState(app.td.method == t1mapp.Constants.METHOD_IR);
            app.TI0EditField.Editable = editable;
            app.TIsEditField.Editable = editable;

        end % ApplyMethodLabelsFcn


        % Set the widget limits and starting values
        function SetWidgetLimitsFcn(app)

            % Purpose:
            %   Puts every slider and range field within the bounds this dataset allows.
            %
            % How it works:
            %   A dimension of one still needs a range of two, since a slider cannot
            %   have equal limits; the widget is disabled elsewhere in that case.

            app.SetRangeFcn({app.SlicesEditField1, app.SlicesSlider1, app.SlicesEditField2, app.SlicesSlider2, ...
                app.SlicesEditField3, app.SlicesSlider3, app.FirstSliceEditField, app.LastSliceEditField}, app.td.ns);
            app.SetRangeFcn({app.InversionEditField, app.InversionSlider}, app.td.nf);
            app.SetRangeFcn({app.DynamicsEditField1, app.DynamicsSlider1}, app.td.nd);
            app.SetRangeFcn({app.DynamicsEditField2, app.DynamicsSlider2, app.DynamicsEditField3, app.DynamicsSlider3, ...
                app.FirstDynamicEditField, app.LastDynamicEditField}, app.td.nMaps);

            app.LastSliceEditField.Value = app.td.ns;
            app.LastDynamicEditField.Value = app.td.nMaps;
            app.abortFitFlag = false;
            app.abortRegFlag = false;

        end % SetWidgetLimitsFcn


        % Limits and a starting value of 1 for a set of widgets
        function SetRangeFcn(app, widgets, n) %#ok<INUSL>

            % Purpose:
            %   Gives sliders and numeric fields the range 1 to n.

            n = max(2, n);

            for k = 1:numel(widgets)
                % the value first, since limits that exclude it would be refused
                widgets{k}.Value = 1;
                widgets{k}.Limits = [1 n];
            end

        end


        % Reset everything a previous dataset left behind
        function ResetForNewDataFcn(app)

            % Purpose:
            %   Clears the options, the plots, the ROI and the selection so that
            %   nothing from the last dataset survives into this one.
            %
            % How it works:
            %   The maps, the mask and the thresholds were already reallocated by the
            %   import. The point selection is set to the method's default.

            app.ModelBasedCheckBox.Value = 0;

            % Window and level
            app.WindowEditField.Value = t1mapp.Constants.DISPLAY_MAX;
            app.LevelEditField.Value = t1mapp.Constants.DISPLAY_LEVEL;
            app.WindowSlider.Value = t1mapp.Constants.DISPLAY_MAX;
            app.LevelSlider.Value = t1mapp.Constants.DISPLAY_LEVEL;
            app.AutoLWButton.Value = 1;

            % Segmentation: nothing of the last dataset's mask to undo
            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];

            % ROI and plots
            app.roiPoints = [];
            app.roiIntDataSigned = [];
            delete(app.roiT1);
            delete(app.roiM0);
            delete(app.crossT1);
            delete(app.crossM0);
            cla(app.RawDataFig);
            cla(app.T1MapFig);
            cla(app.M0MapFig);
            cla(app.R2MapFig);
            cla(app.T1linearPlot);
            app.T1ViewField1.Value = " ";
            app.T1ViewField2.Value = " ";

            app.SetDefaultSelectionFcn;

        end % ResetForNewDataFcn


        % Copy the acquisition outputs to the fields that display them
        function MirrorDataOutputsFcn(app)

            % Purpose:
            %   Puts every acquisition value the app shows into its display field,
            %   in one place.
            %
            % How it works:
            %   These fields are read-only mirrors of the dataset: nothing below the
            %   app writes them, and nothing reads them back for a decision. The TR
            %   and TI rows show what the method has: a TR series for a saturation
            %   recovery, a TI series for an inversion recovery, and the flip angles
            %   in the TI row for a flip angle scan, whose frame count takes the
            %   place of the dynamics. tests/tMirrors checks that the import sets
            %   everything shown here.

            app.DatafileEditField.Value = char(app.td.dataFileName);
            app.FOVViewField.Value = app.td.fov;
            app.dimxViewField.Value = app.td.dimx;
            app.dimyViewField.Value = app.td.dimy;
            app.SlicesViewField.Value = app.td.ns;
            app.SLTViewField.Value = app.td.sl;
            app.AspectRatioViewField.Value = app.td.aspectRatio;

            switch app.td.method

                case t1mapp.Constants.METHOD_IR
                    app.TR0ViewField.Value = app.td.tr;
                    app.TRsEditField.Value = app.td.tr;
                    app.NrTRsViewField.Value = 1;
                    app.TI0EditField.Value = app.td.fitValues(1);
                    app.TIsEditField.Value = app.td.fitValues(end);
                    app.NrTIsViewField.Value = app.td.nf;
                    app.NrDynamicsViewField.Value = app.td.nd;

                case t1mapp.Constants.METHOD_SR
                    app.TR0ViewField.Value = app.td.fitValues(1);
                    app.TRsEditField.Value = app.td.fitValues(end);
                    app.NrTRsViewField.Value = app.td.nf;
                    app.TI0EditField.Value = 0;
                    app.TIsEditField.Value = 0;
                    app.NrTIsViewField.Value = 0;
                    app.NrDynamicsViewField.Value = app.td.nd;

                otherwise
                    app.TR0ViewField.Value = app.td.tr;
                    app.TRsEditField.Value = app.td.tr;
                    app.NrTRsViewField.Value = 1;
                    app.TI0EditField.Value = app.td.fitValues(1);
                    app.TIsEditField.Value = app.td.fitValues(end);
                    app.NrTIsViewField.Value = app.td.nd;
                    app.NrDynamicsViewField.Value = app.td.nf;

            end

        end % MirrorDataOutputsFcn


        % Copy the reconstruction outputs to the fields that display them
        function MirrorRecoOutputsFcn(app)

            % Purpose:
            %   Puts the values a reconstruction can change into their display fields.
            %
            % How it works:
            %   Removing readout oversampling shortens the readout, so the matrix on
            %   screen is not the acquired one. This runs after the crop.

            app.dimxViewField.Value = app.td.dimx;
            app.dimyViewField.Value = app.td.dimy;

        end % MirrorRecoOutputsFcn


        % Read the point selection boxes into the dataset
        function MakeSelectionFcn(app)

            % Purpose:
            %   Tells the dataset which points of the fitted series the user wants
            %   used.
            %
            % How it works:
            %   There are 96 boxes; points beyond them are always used.

            selection = true(app.td.nPoints,1);

            for k = 1:min(96, app.td.nPoints)
                selection(k) = logical(app.("CheckBox_" + k).Value);
            end

            app.td.pointSelection = selection;

        end % MakeSelectionFcn


        % The default point selection for the method
        function SetDefaultSelectionFcn(app)

            % Purpose:
            %   Ticks every point, for every method. A point is left out of the
            %   fit by unticking it in the fit parameters.

            for k = 1:96
                app.("CheckBox_" + k).Value = 1;
            end

            app.MakeSelectionFcn;

        end % SetDefaultSelectionFcn


        % Show the middle slice at the reference point of the series
        function ShowReferenceImageFcn(app)

            % Purpose:
            %   After a reconstruction, starts the viewer on the image the mask and
            %   the registration use.

            slice = max(1, round(app.td.ns/2));
            ref = t1mapp.fit.referenceIndex(app.td);

            app.SlicesEditField1.Value = slice;
            app.SlicesSlider1.Value = slice;

            if app.td.fitDim == 5
                app.DynamicsEditField1.Value = ref;
                app.DynamicsSlider1.Value = ref;
                app.InversionEditField.Value = 1;
                app.InversionSlider.Value = 1;
            else
                app.InversionEditField.Value = ref;
                app.InversionSlider.Value = ref;
                app.DynamicsEditField1.Value = 1;
                app.DynamicsSlider1.Value = 1;
            end

        end % ShowReferenceImageFcn


        % Change the map slice
        function ChangeMapSlices(app, value)

            value = min(max(1, round(value)), app.SlicesEditField2.Limits(2));
            app.SlicesEditField2.Value = value;
            app.SlicesSlider2.Value = value;
            app.SlicesEditField3.Value = value;
            app.SlicesSlider3.Value = value;

        end


        % Change the map index
        function ChangeMapIndex(app, value)

            value = min(max(1, round(value)), app.DynamicsEditField2.Limits(2));
            app.DynamicsEditField2.Value = value;
            app.DynamicsSlider2.Value = value;
            app.DynamicsEditField3.Value = value;
            app.DynamicsSlider3.Value = value;

        end


        % A T1 scale that shows the map well
        function AutoScaleFcn(app)

            % Purpose:
            %   Sets the T1 scale from 0 to twice the median T1 of the maps, rounded up
            %   to a hundred ms.

            values = nonzeros(app.td.t1map);
            scale = 100*ceil(2*median(values)/100);

            if isempty(values) || ~isfinite(scale)
                scale = t1mapp.defaultDisplayParams().t1Scale;
            end

            app.T1ScaleMinEditField.Value = 0;
            app.T1ScaleEditField.Value = min(max(scale, 100), t1mapp.Constants.MAX_T1);

        end % AutoScaleFcn


        % Least-squares T1 fitting, pixel by pixel
        function FitPixelwiseFcn(app, fitParams)

            % Purpose:
            %   Fits every pixel of the selected slices and maps.
            %
            % How it works:
            %   One slice at a time, so that the maps are drawn as they fill in and the
            %   abort button is honoured between slices.

            reporter = AppReporter(app,'fit');
            slices = app.FirstSliceEditField.Value:app.LastSliceEditField.Value;
            maps = app.FirstDynamicEditField.Value:app.LastDynamicEditField.Value;
            reporter.setTotal(numel(slices)*numel(maps));
            reporter.setAborted(false);

            for map = maps
                for slice = slices

                    if reporter.aborted()
                        break
                    end

                    app.TextMessage(sprintf("Fitting slice %d, %s %d ...", slice, app.td.mapAxis, map));

                    [app.td.m0map(:,:,slice,map), app.td.t1map(:,:,slice,map), app.td.r2map(:,:,slice,map)] = ...
                        t1mapp.fit.fitSlice(app.td, fitParams, slice, map);

                    app.ChangeMapSlices(slice);
                    app.ChangeMapIndex(map);
                    app.AutoScaleFcn;
                    app.validFitFlag = true;
                    app.PlotMaps;
                    app.validFitFlag = false;

                    reporter.advance();

                end
            end

            reporter.finish();

        end % FitPixelwiseFcn


        % Model-based T1 fitting
        function FitModelBasedFcn(app, fitParams)

            % Purpose:
            %   Fits the maps of the selected slices and dynamics straight from k-space
            %   with BART.
            %
            % How it works:
            %   Slice by slice, as the pixel-wise fit does. The model-based fit gives no
            %   goodness of fit, so the R-square map stays empty.

            reporter = AppReporter(app,'fit');
            slices = app.FirstSliceEditField.Value:app.LastSliceEditField.Value;
            maps = app.FirstDynamicEditField.Value:app.LastDynamicEditField.Value;
            reporter.setTotal(numel(slices)*numel(maps));
            reporter.setAborted(false);

            for map = maps
                for slice = slices

                    if reporter.aborted()
                        break
                    end

                    app.TextMessage(sprintf("Model-based fit of slice %d, dynamic %d ...", slice, map));

                    [app.td.m0map(:,:,slice,map), app.td.t1map(:,:,slice,map)] = ...
                        t1mapp.fit.mobaIRfit(app.td, fitParams, slice, map, AppReporter(app));
                    app.td.r2map(:,:,slice,map) = 0;

                    app.ChangeMapSlices(slice);
                    app.ChangeMapIndex(map);
                    app.AutoScaleFcn;
                    app.validFitFlag = true;
                    app.PlotMaps;
                    app.validFitFlag = false;

                    reporter.advance();

                end
            end

            reporter.finish();

        end % FitModelBasedFcn


        % Write the animations, and photograph the window beside them
        function ExportGifsFcn(app, gifExportBaseFolder)

            % Purpose:
            %   Makes the numbered output folder for this export, puts a picture of
            %   the app's window in it, and writes the animations.
            %
            % How it works:
            %   The photographs are why this lives in the app: switching tabs and
            %   calling exportapp is GUI work. exportGifT1 is given the finished folder.

            ready = false;
            cnt = 1;
            while ~ready
                gifOutputFolder = strcat(fullfile(gifExportBaseFolder,strcat(app.td.tag,'T1'),num2str(cnt)),filesep);
                if ~exist(gifOutputFolder, 'dir')
                    mkdir(gifOutputFolder);
                    ready = true;
                end
                cnt = cnt + 1;
            end

            app.TabGroup2.SelectedTab = app.RawImagesTab;
            app.TabGroup3.SelectedTab = app.M0R2mapTab;
            exportapp(app.T1mappUIFigure,fullfile(gifOutputFolder,strcat("M0map",app.td.tag,".png")));
            app.TabGroup3.SelectedTab = app.T1mapTab;
            exportapp(app.T1mappUIFigure,fullfile(gifOutputFolder,strcat("T1map",app.td.tag,".png")));

            t1mapp.io.exportGifT1(app.td, gatherFitParams(app), gatherDisplayParams(app), gifOutputFolder);

        end % ExportGifsFcn


        % Write a parameter set to the log
        function LogParamsFcn(app, label, params)

            % Purpose:
            %   Records exactly which parameters an import, a reconstruction, a
            %   registration or a fit ran with, so that a result can be traced back
            %   to what produced it.
            %
            % How it works:
            %   One line of JSON, to the log only; the message pane is not the place
            %   for it.

            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s parameters: %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        label, jsonencode(params));
                end
            catch
            end

        end % LogParamsFcn


        % Shut the app down
        function CloseAppFcn(app)

            % Purpose:
            %   Shut the app down, whether the exit button or the window's close box was
            %   used, also while the app is still busy, and leave the MATLAB session as it
            %   was found.
            %
            % How it works:
            %   While app code is still running -- the startup, a step or a loop -- deleting
            %   the app would make that code fail on a deleted object. The close is then
            %   deferred: the window is hidden, what runs is asked to stop, and a timer
            %   finishes the close once no app code runs any more (DeferredCloseFcn).
            %
            %   Otherwise each step is guarded on its own, so a failure in one cannot leave
            %   the window open or the log unclosed: the log is closed, and the path, the
            %   folder and the warning state MATLAB had are put back.
            %
            %   The figure is deleted before the app. Deleting only the app from the close
            %   box left the figure registered as the running app, and the app could then
            %   not be started again. Both go before the app's folders leave the search
            %   path: deleting the figure runs the app's destructor, which MATLAB cannot
            %   find once they are gone. What the restoring needs is kept first.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   none - the app is closed, or its close is scheduled

            % Still busy: stop the fit or registration and close once it has finished
            if app.AppCodeRunningFcn

                if ~app.closeRequested
                    app.closeRequested = true;
                    app.abortFitFlag = true;
                    app.abortRegFlag = true;
                    app.TextMessage('Closing when the current step has finished ...');
                    app.T1mappUIFigure.Visible = 'off';
                    app.closeTimer = timer('Name', 'T1mappCloseTimer', ...
                        'ExecutionMode', 'fixedSpacing', 'Period', 0.5, 'StartDelay', 0.5, ...
                        'TasksToExecute', 7200, ...
                        'TimerFcn', @(t,~) isvalid(app) && app.DeferredCloseFcn(t));
                    start(app.closeTimer);
                end

                return

            end

            % Close the log file
            try
                app.TextMessage('Closing T1mapp ...');
                if app.logFile > 2
                    fclose(app.logFile);
                end
                app.logFile = -1;
            catch
            end

            % Keep what the restoring needs; the app is deleted before it is used
            appRoot = app.appFolder;
            startFolder = app.oldFolder;
            startWarnings = app.oldWarningState;

            % Delete the figure, then the app, while the app folders are still on the
            % search path: deleting the figure runs the app's destructor
            try
                delete(app.T1mappUIFigure);
            catch
            end
            try
                delete(app);
            catch
            end

            % Remove the app folders from the search path, and return to the folder MATLAB
            % was in
            if ~isdeployed
                try
                    rmpath(genpath(appRoot));
                    cd(startFolder);
                catch
                end
            end

            % Restore the warning state MATLAB had
            try
                if ~isempty(startWarnings)
                    warning(startWarnings);
                end
            catch
            end

        end % CloseAppFcn


        % Finish a close that had to wait
        function done = DeferredCloseFcn(app, t)

            % Purpose:
            %   Complete a close that was requested while app code was still running.
            %
            % How it works:
            %   The close timer calls this twice a second. While app code still runs
            %   nothing else happens. Once it has stopped, one more tick is waited, so
            %   that the callback that ended the step has returned as well, and the app is
            %   then closed the normal way.
            %
            % Inputs:
            %   app - the app being closed
            %   t   - the close timer
            %
            % Output:
            %   done - always true, since the timer's function handle needs a value

            done = true;

            try

                % Keep the window hidden; a dialog that returned may have raised it
                app.T1mappUIFigure.Visible = 'off';

                % Still running: wait for the next tick
                if app.AppCodeRunningFcn
                    t.UserData = 0;
                    return
                end

                % Idle for the first time: wait one more tick
                if isempty(t.UserData) || t.UserData < 1
                    t.UserData = 1;
                    return
                end

                % Idle twice in a row: close
                stop(t);
                delete(t);
                app.closeTimer = [];
                app.CloseAppFcn;

            catch

                % A timer that fails is removed rather than left firing
                try
                    stop(t);
                    delete(t);
                catch
                end

            end

        end % DeferredCloseFcn


        % Check whether app code is running
        function running = AppCodeRunningFcn(app)

            % Purpose:
            %   Tell whether closing the app now would break code that is still running.
            %
            % How it works:
            %   Any method of the app still on the call stack -- a callback, the startup,
            %   a loop -- is running underneath the close request, or underneath the timer
            %   that asks. The closing methods themselves do not count, and neither do
            %   anonymous functions, whose names carry the method that created them.
            %
            %   Where the stack cannot be read, the BUSY status that every step sets is
            %   used instead.
            %
            % Inputs:
            %   app - the running app
            %
            % Output:
            %   running - true while app code other than the closing methods runs

            running = app.busyFlag;

            % The methods that do the closing
            closing = ["CloseAppFcn", "DeferredCloseFcn", "AppCodeRunningFcn", ...
                "ExitButtonPushed", "UIFigureCloseRequest"];

            % The app's own methods on the call stack
            try
                names = string({dbstack.name});
                mine = startsWith(names, "T1mapp.");
                if any(mine)
                    running = any(mine & ~contains(names, "@(") & ~contains(names, closing));
                end
            catch
            end

        end % AppCodeRunningFcn


        % Which colour map one panel's radio buttons are set to
        function cmap = ColorMapFromButtonsFcn(app, n, cmap)

            % Purpose:
            %   Reads the five colour map radio buttons of one panel and gives back the
            %   map they name.
            %
            % How it works:
            %   Panel 1 is T1, whose buttons carry no number; 2 is M0 and 3 is R-square.
            %   The first entry is forced to black so the background of a map stays
            %   black whatever the colour map does at its bottom end.

            suffix = "";
            if n > 1
                suffix = string(n);
            end

            if app.("grayButton" + suffix).Value == 1
                cmap = gray;
            end

            if app.("autumnButton" + suffix).Value == 1
                cmap = autumn;
            end

            if app.("winterButton" + suffix).Value == 1
                cmap = winter;
            end

            if app.("parulaButton" + suffix).Value == 1
                cmap = parula;
            end

            if app.("lipariButton" + suffix).Value == 1
                cmap = t1mapp.util.lipariColorMap();
            end

            cmap(1,:) = [0 0 0];

        end % ColorMapFromButtonsFcn


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)

            % Every message goes to the log as well, with the time of day, in its
            % own guard so that it reaches the file even when the pane cannot take
            % it, as while the interface is being torn down
            try
                if app.logFile > 2
                    fprintf(app.logFile, '%s  %s\n', char(datetime('now','Format','HH:mm:ss')), ...
                        char(strjoin(string(message), ' ')));
                end
            catch
            end

            try
                app.MessageBox.Value = [string(message) ; string(app.MessageBox.Value)];
                app.MessageBox.Value = app.MessageBox.Value(1:min(50,end),1);
                drawnow;
            catch
            end

        end % TextMessage


        % Status icon
        function SetStatus(app,status)

            % BUSY marks a running step until OK or ERROR ends it; a WARNING leaves the
            % flag as it was. A close request waits for the flag to clear
            if status == 3
                app.busyFlag = true;
            elseif status == 0 || status == 2
                app.busyFlag = false;
            end

            switch status

                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 12;
                    app.StatusLabel.Text = 'BUSY';

            end

            drawnow;

        end

        % Load a dataset and reconstruct it
        function ok = LoadDataset(app, folder, fileName)

            % Purpose:
            %   Loads an MRD dataset, refuses it when it is not a T1 measurement, and
            %   reconstructs it when it is.
            %
            % How it works:
            %   The work the load button does after the file dialog, public so that
            %   a script or a test can drive the app without one. The reason a
            %   dataset is refused is shown in the message pane.
            %
            % Inputs:
            %   folder   - the folder holding the MRD file
            %   fileName - the MRD file name
            %
            % Outputs:
            %   ok - true when a dataset was loaded and reconstructed

            ok = false;

            % What a refused scan leaves as it was: the dataset held, its flags, folder and
            % lamps (the import does not touch t1Data when it refuses)
            previous.flags = [app.validDataFlag, app.validRecoFlag, app.validRegFlag, app.validFitFlag];
            previous.folder = app.mrdImportFolder;
            previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};
            refused = false;

            app.SetLights([3,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);

            try


                folder = char(folder);
                if ~endsWith(folder, filesep)
                    folder = [folder filesep];
                end
                app.mrdImportFolder = folder;

                app.TextMessage(strcat("Loading ", fileName, " ..."));
                importParams = gatherImportParams(app);
                app.LogParamsFcn('import', importParams);
                [valid, reason] = app.td.importMRD(folder, fileName, importParams, AppReporter(app));

                if ~valid
                    refused = true;
                    ME = MException('T1mapp:InvalidFile', '%s', "ERROR: " + reason + " ...");
                    throw(ME);
                end

                % A new dataset: whatever was reconstructed, registered or fitted is gone.
                % Cleared here, after the import: cleared before it, a refused scan (a
                % localizer) stranded the dataset still held, its images and maps on screen
                % and nothing but Load and Exit working (2026-09-30)
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;

                app.SetParameters;
                app.validDataFlag = true;
                app.TextMessage(sprintf("Dataset: %d frames, %d dynamics, %d slices, %d x %d, series %s ...", ...
                    app.td.nf, app.td.nd, app.td.ns, app.td.dimx, app.td.dimy, mat2str(round(app.td.fitValues',1))));

                app.SetFitButtons(1);
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([1,1,0,0,0,0,0,1]);
                app.SetStatus(0);

                % A fast FFT reconstruction
                app.CSrecoCheckBox.Value = 0;
                app.RecoImagesButtonPushed;
                ok = app.validRecoFlag;

            catch ME

                app.TextMessage(ME.message);
                if ~startsWith(string(ME.message), "ERROR")
                    app.TextMessage('ERROR: Something went wrong while loading the data ...');
                end
                if refused && previous.flags(1)
                    % The dataset held is untouched: usable as it was
                    app.validDataFlag = previous.flags(1);
                    app.validRecoFlag = previous.flags(2);
                    app.validRegFlag = previous.flags(3);
                    app.validFitFlag = previous.flags(4);
                    app.mrdImportFolder = previous.folder;
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    fitted = double(app.validFitFlag);
                    if app.validRecoFlag
                        app.SetImageSelectionButtons(1);
                        app.SetSegmentationButtons(1);
                        app.SetMapButtons(fitted);
                        app.SetFitButtons(1);
                        app.SetButtons([1,1,1,1,1,fitted,fitted,1]);
                    else
                        app.SetFitButtons(1);
                        app.SetButtons([1,1,0,0,0,0,0,1]);
                    end
                else
                    app.validDataFlag = false;
                    app.validRecoFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetFitButtons(0);
                    app.SetLights([0,0,0,0,0,0,0]);
                    app.SetButtons([1,0,0,0,0,0,0,1]);
                end
                app.SetStatus(2);

            end

        end % LoadDataset


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)

            % The window is already showing, so closing it during the startup waits for
            % the startup to finish, as it does during any other step
            app.busyFlag = true;
            app.T1mappUIFigure.CloseRequestFcn = @(~,~) app.CloseAppFcn;

            % Disable unnecessary warnings
            clc;
            app.oldWarningState = warning;   % put back when the app closes
            warning off;

            % Set app theme to light
            theme(app.T1mappUIFigure,"light")

            % Main window noname
            app.T1mappUIFigure.Name = '';

            % Empty file import and export folders
            app.mrdImportFolder = '';
            app.dicomExportFolder = '';
            app.gifExportFolder = '';
            app.roiExportFolder = '';
            app.mrdFlag = true;

            % Add subfolders to search folder
            if ~isdeployed
                app.oldFolder = pwd;
                info = dir(which('T1mapp.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(pwd));
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));

                % Retired copies live in backup/ and must not be reachable.
                % genpath walks folders in alphabetical order, so backup comes
                % before functions and an old copy there would be found first.
                if isfolder(fullfile(pwd,'backup'))
                    rmpath(genpath(fullfile(pwd,'backup')));
                end
            end

            % The dataset this session works on. Created here rather than
            % earlier because @t1Data sits beside the app, so the class is only
            % reachable once the paths above are set.
            app.td = t1Data;

            % The log first, so that anything that goes wrong from here on is
            % recorded, then the settings the interface starts from. Neither
            % raises: without a log the session is not logged, and a damaged
            % settings file is repaired or replaced.
            [app.logFileName, app.logFile] = t1mapp.io.makeLogFile(app.appVersion, AppReporter(app));
            app.TextMessage(strcat("T1mapp ", app.appVersion, ", log file = ", app.logFileName, " ..."));
            app.jsonPar = t1mapp.io.readSettingsFile(app.appVersion, AppReporter(app));
            app.mrdImportFolder = app.jsonPar.mrdImportPath;
            app.dicomExportFolder = app.jsonPar.defaultExportPath;
            app.gifExportFolder = app.jsonPar.defaultExportPath;
            app.roiExportFolder = app.jsonPar.defaultExportPath;

            % Initialize user interface
            app.InitializeUI;

            % Set app colors
            app.ColorsButtonGroupSelectionChanged;

            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    if any(strfind(result,'ARM64'))
                        app.TextMessage('Apple arm64 version ...');
                    else
                        app.TextMessage('Apple Intel version ...');
                    end
                else
                    app.TextMessage('PC Intel version ...');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix. The binary is located with resourcePath rather than which(),
            % because a compiled app has no MATLAB path.
            try
                if ismac || isunix
                    result = getenv('PATH');
                    for extra = {'/usr/local/bin','/usr/local/lib'}
                        if ~contains(result, extra{1})
                            result = [result pathsep extra{1}]; %#ok<AGROW>
                        end
                    end
                    setenv('PATH', result);
                else
                    elastixExe = t1mapp.util.resourcePath('elastix.exe');
                    if ~isempty(elastixExe)
                        elastixFolder = fileparts(elastixExe);
                        result = getenv('PATH');
                        if ~contains(result, elastixFolder)
                            setenv('PATH', [result pathsep elastixFolder]);
                        end
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Whether elastix can actually be run, reported once
            app.elastixDetectedFlag = false;
            try
                probe = t1mapp.util.useBundledElastix('elastix --version');
                [elastixStatus, elastixReply] = system(probe);
                app.elastixDetectedFlag = elastixStatus == 0 && contains(elastixReply,'version');
            catch
            end

            if app.elastixDetectedFlag
                app.TextMessage('Elastix detected ...');
            else
                app.TextMessage('WARNING: elastix not available; registration will use Matlab imregister ...');
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                app.bartVersion = strtrim(bart(AppReporter(app),'version'));
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected, ' + string(app.bartVersion) + ' ...');
                if ~t1mapp.util.bartVersionAtLeast(app.bartVersion)
                    app.bartDetectedFlag = false;
                    app.TextMessage("WARNING: BART " + string(app.bartVersion) + ...
                        " is older than the required " + t1mapp.Constants.MIN_BART_VERSION + " ...");
                    app.TextMessage('WARNING: CS reconstruction and model-based fitting are disabled ...');
                    app.SetStatus(1);
                end
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Check if BART was compiled with GPU support
            app.GPUpresentFlag = false;
            if app.bartDetectedFlag
                try
                    rec = bart(AppReporter(app),'pics -l2 -g ',[0 1 0 ; 1 1 1 ; 0 1 0],[1 1 1 ; 1 1 1 ; 1 1 1]); %#ok<NASGU>
                    app.TextMessage('BART compiled with GPU support ...');
                    app.GPUpresentFlag = true;
                catch
                    app.TextMessage('BART compiled without GPU support ...');
                end
            end

            % Old logs, now that the number to keep is known
            t1mapp.io.pruneLogFiles(app.logFileName, app.jsonPar.logFilesToKeep, AppReporter(app));

            % The final MATLAB version: no version check, since later versions are the
            % Python apps (the owner, 2026-10-02). CheckVersionFnc is kept, not called
            app.TextMessage("This is the final MATLAB version of T1mapp (" + app.appVersion + ") ...");
            app.TextMessage("Later versions are Python apps: https://github.com/Moby1971/MRI-apps ...");

            % OK
            app.SetStatus(0);
            app.SetButtons([1,0,0,0,0,0,0,1]);
            app.TextMessage("Ready ...");

        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS: backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"
                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiDarkGrey, app.lightblue, app.guiGrey};

                case "black/white"
                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiDarkGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)

            % Load an inversion recovery, saturation recovery or variable flip
            % angle dataset

            % Check the standard MRD folder first if the platform is a pc, once per session
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('MRD folder found in Windows registry ...');
                    app.TextMessage(strcat("MRD folder = ",folderName," ..."));
                catch
                    app.TextMessage('Trying standard MRD folder ...');
                    folderName = 'c:\smis\dev\MRD\';
                end
                if exist(folderName, 'dir') && app.mrdFlag && isempty(app.jsonPar.mrdImportPath)
                    app.mrdImportFolder = folderName;
                    app.mrdFlag = false;
                end
            end

            % Select a data file
            f = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
            [mrdFile, mrdFolder] = uigetfile(fullfile(app.mrdImportFolder,'*.MRD;*.mrd'));
            delete(f);
            if ~app.closeRequested
                figure(app.T1mappUIFigure);
            end

            if ~ischar(mrdFile)
                app.TextMessage('User cancel ...');
                return
            end

            app.LoadDataset(mrdFolder, mrdFile);

        end

        % Button pushed function: RecoImagesButton
        function RecoImagesButtonPushed(app, event)

            if app.validDataFlag

                % What a failure puts back: the images, their size and what setImages
                % resets with them, the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.size = [app.td.dimx, app.td.dimy];
                previous.t1map = app.td.t1map;
                previous.m0map = app.td.m0map;
                previous.r2map = app.td.r2map;
                previous.mask = app.td.mask;
                previous.threshold = app.td.threshold;
                previous.flags = [app.validRecoFlag, app.validRegFlag, app.validFitFlag];
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                % Indicate busy
                app.SetLights([1,3,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawImagesTab;

                try

                    % One struct describes this reconstruction, gathered once, so that
                    % nothing below the app reads a widget.
                    recoParams = gatherRecoParams(app);
                    app.LogParamsFcn('reconstruction', recoParams);

                    if recoParams.csReco
                        app.TextMessage('CS reconstruction ...');
                        images = t1mapp.reco.CSreco(app.td, recoParams, AppReporter(app));
                    else
                        app.TextMessage('FFT reconstruction ...');
                        images = t1mapp.reco.fftReco(app.td);
                    end

                    % Readout oversampling and orientation
                    images = t1mapp.reco.cropOversampling(images, app.td.parameters);
                    images = t1mapp.reco.orientImages(images, app.td.parameters);

                    % Denoising, along the series the fit runs over, with the window chosen
                    % for the data (t1mapp.reco.mppcaWindow)
                    if recoParams.denoise
                        app.TextMessage('PCA denoising ...');
                        [images, pcaWindow] = t1mapp.reco.denoiseSeries(images, app.td.fitDim, t1mapp.reco.voxelSizeOf(app.td, images));
                        if ~isempty(pcaWindow)
                            app.TextMessage(strcat("PCA denoising window ", strjoin(string(pcaWindow), " x "), " ..."));
                        end
                    end

                    % Gibbs ringing suppression
                    if recoParams.deRing
                        app.TextMessage('Gibbs ringing suppression ...');
                        images = unRing(images);
                    end

                    % Normalize to the display range
                    images = t1mapp.Constants.DISPLAY_MAX*images/max(images(:));
                    app.td.setImages(images);
                    app.MirrorRecoOutputsFcn;

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;

                    % Show the images
                    app.ShowReferenceImageFcn;
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure: everything back as it was before the step, so an earlier
                    % reconstruction, registration and fit stay usable
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during the reconstruction ...');
                    app.td.images = previous.images;
                    app.td.dimx = previous.size(1);
                    app.td.dimy = previous.size(2);
                    app.td.t1map = previous.t1map;
                    app.td.m0map = previous.m0map;
                    app.td.r2map = previous.r2map;
                    app.td.mask = previous.mask;
                    app.td.threshold = previous.threshold;
                    app.validRecoFlag = previous.flags(1);
                    app.validRegFlag = previous.flags(2);
                    app.validFitFlag = previous.flags(3);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp2.Color = app.red;

                    try
                        app.MirrorRecoOutputsFcn;
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    reco = double(app.validRecoFlag);
                    fitted = double(app.validFitFlag);
                    app.SetButtons([1,1,reco,reco,reco,fitted,fitted,1]);
                    app.SetImageSelectionButtons(reco);
                    app.SetSegmentationButtons(reco);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: TI0EditField, TIsEditField
        function TIsEditFieldValueChanged(app, event)

            % The first and last inversion time, when the ones computed from the
            % sequence parameters are not the ones that were played out
            try
                app.td.setInversionTimes(app.TI0EditField.Value, app.TIsEditField.Value);
                app.TextMessage(strcat("Inversion times = ",num2str(round(app.td.fitValues',1))," ms ..."));
                if app.validFitFlag
                    % Maps fitted with the previous inversion times no longer match them
                    app.validFitFlag = false;
                    app.TextMessage('WARNING: the inversion times changed, fit the maps again ...');
                    app.SetLights([2,2,2,2,0,0,0]);
                    app.ExportDCMButton.Enable = 'off';
                    app.ExportGIFButton.Enable = 'off';
                    app.SetMapButtons(0);
                end
            catch ME
                app.TextMessage(ME.message);
            end

            app.MirrorDataOutputsFcn;

        end

        % Value changed function: DynamicsEditField1, InversionEditField, 
        % ...and 1 other component
        function SlicesEditFieldValue1Changed(app, event)

            % Adjust the sliders
            app.SlicesSlider1.Value = app.SlicesEditField1.Value;
            app.InversionSlider.Value = app.InversionEditField.Value;
            app.DynamicsSlider1.Value = app.DynamicsEditField1.Value;

            % Plot the raw image
            app.PlotImageFig;

        end

        % Value changed function: SlicesSlider1
        function SlicesSlider1ValueChanged(app, event)

            app.SlicesEditField1.Value = round(app.SlicesSlider1.Value);
            app.SlicesEditFieldValue1Changed;

        end

        % Value changed function: InversionSlider
        function InversionSliderValueChanged(app, event)

            app.InversionEditField.Value = round(app.InversionSlider.Value);
            app.SlicesEditFieldValue1Changed;

        end

        % Value changed function: DynamicsSlider1
        function DynamicsSlider1ValueChanged(app, event)

            app.DynamicsEditField1.Value = round(app.DynamicsSlider1.Value);
            app.SlicesEditFieldValue1Changed;

        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validRecoFlag

                % What a failure puts back: the images, the maps, mask and thresholds
                % resetMaps clears, the flags that describe them, and the lamps
                previous.images = app.td.images;
                previous.t1map = app.td.t1map;
                previous.m0map = app.td.m0map;
                previous.r2map = app.td.r2map;
                previous.mask = app.td.mask;
                previous.threshold = app.td.threshold;
                previous.validRegFlag = app.validRegFlag;
                previous.validFitFlag = app.validFitFlag;
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                app.validRegFlag = false;
                app.validFitFlag = false;

                % Indicate busy
                app.SetLights([1,1,3,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.abortRegFlag = false;
                app.AbortRegButton.Enable = 'on';

                try

                    % Every image of the series to the reference image of its slice
                    reporter = AppReporter(app,'registration');
                    regParams = gatherRegParams(app);
                    app.LogParamsFcn('registration', regParams);
                    [images, usedFallback] = t1mapp.reco.registerImages(app.td.images, app.td.fitDim, ...
                        t1mapp.fit.referenceIndex(app.td), regParams, reporter);

                    app.td.images = images;
                    app.td.resetMaps();

                    if usedFallback
                        app.TextMessage("WARNING: elastix was not available; registration used Matlab imregister ...");
                    end

                    if reporter.aborted()
                        app.TextMessage("WARNING: registration aborted, the images are partly registered ...");
                    end

                    app.validRegFlag = true;
                    app.PlotImageFig;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    % Everything back as it was before the step, so a registration that
                    % failed halfway leaves nothing half done, and the steps whose results
                    % are intact stay usable
                    app.td.images = previous.images;
                    app.td.t1map = previous.t1map;
                    app.td.m0map = previous.m0map;
                    app.td.r2map = previous.r2map;
                    app.td.mask = previous.mask;
                    app.td.threshold = previous.threshold;
                    app.validRegFlag = previous.validRegFlag;
                    app.validFitFlag = previous.validFitFlag;
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp3.Color = app.red;

                    try
                        app.PlotImageFig;
                        app.PlotMaps;
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetFitButtons(1);
                    app.SetMapButtons(fitted);
                    app.AbortRegButton.Enable = 'off';
                    app.SetButtons([1,1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)

            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)

            if app.validRecoFlag

                % Indicate busy
                app.SetLights([2,2,2,3,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ...');

                % A failure halfway would leave a mask that no longer matches its
                % thresholds, so both are put back as they were
                previousMask = app.td.mask;
                previousThreshold = app.td.threshold;

                try

                    % An Otsu threshold per slice and map, on the reference image of the
                    % series, which shows the anatomy best
                    ref = t1mapp.fit.referenceIndex(app.td);

                    for map = 1:app.td.nMaps
                        for slice = 1:app.td.ns
                            stack = t1mapp.fit.seriesOf(app.td.images, app.td.fitDim, slice, map);
                            im = reshape(stack(ref,:,:), app.td.dimx, app.td.dimy);
                            % mat2gray maps [min, max] to [0, 1], so the level is min + t*(max - min);
                            % t*max ignored the minimum (2026-09-30)
                            app.td.threshold(slice,map) = min(im(:)) + graythresh(mat2gray(im))*(max(im(:)) - min(im(:)));
                            app.td.mask(:,:,slice,map) = t1mapp.fit.applyThreshold(im, app.td.threshold(slice,map));
                        end
                    end

                    % Display the mask in the image
                    app.PlotImageFig;

                    app.SetLights([2,2,2,1,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(0);
                    app.SetStatus(0);

                catch ME

                    app.td.mask = previousMask;
                    app.td.threshold = previousThreshold;
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong while thresholding the images ...');
                    app.SetLights([2,2,2,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(double(app.validFitFlag));
                    app.SetStatus(2);

                end

            end

        end

        % Value changed function: thresholdEditField
        function thresholdEditFieldValueChanged(app, event)

            % A failure halfway would leave a mask that is partly changed, so the mask
            % and thresholds are put back as they were
            previousMask = app.td.mask;
            previousThreshold = app.td.threshold;

            try

                % The current slice and map
                slice = app.SlicesEditField1.Value;
                map = app.RawMapIndexFcn;

                % The new threshold for this slice and map
                app.td.threshold(slice,map) = app.thresholdEditField.Value;
                app.ThresholdSlider.Value = app.thresholdEditField.Value;

                stack = t1mapp.fit.seriesOf(app.td.images, app.td.fitDim, slice, map);
                im = reshape(stack(t1mapp.fit.referenceIndex(app.td),:,:), app.td.dimx, app.td.dimy);
                app.td.mask(:,:,slice,map) = t1mapp.fit.applyThreshold(im, app.td.threshold(slice,map));

                app.PlotImageFig;

            catch ME

                app.td.mask = previousMask;
                app.td.threshold = previousThreshold;
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the mask could not be changed ...');
                app.SetStatus(2);

            end

        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)

            app.thresholdEditField.Value = round(app.ThresholdSlider.Value);
            app.thresholdEditFieldValueChanged;

        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)

            app.MaskOperationFcn("erode");

        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)

            app.MaskOperationFcn("dilate");

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)

            app.MaskOperationFcn("close");

        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)

            app.MaskOperationFcn("open");

        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)

            % Only the last operation is undone, and only while the mask is still the
            % one it left, so a threshold or a mask made since then is not overwritten
            if ~isempty(app.maskAfterOperation) && isequal(app.td.mask == 1, app.maskAfterOperation)
                app.td.mask = double(app.maskBeforeOperation);
                app.TextMessage('The last mask operation is undone ...');
                app.PlotImageFig;
            else
                app.TextMessage('WARNING: the mask has changed since the last operation, nothing to undo ...');
            end

            app.maskBeforeOperation = [];
            app.maskAfterOperation = [];
            app.UndoButton.Enable = 'off';

        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)

            app.FirstSliceEditField.Value = min(app.FirstSliceEditField.Value, app.td.ns);
            app.LastSliceEditField.Value = min(app.LastSliceEditField.Value, app.td.ns);

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end

        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function FirstDynamicEditFieldValueChanged(app, event)

            app.FirstDynamicEditField.Value = min(app.FirstDynamicEditField.Value, app.td.nMaps);
            app.LastDynamicEditField.Value = min(app.LastDynamicEditField.Value, app.td.nMaps);

            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end

        end

        % Button pushed function: FitT1Button
        function FitT1ButtonPushed(app, event)

            if app.validRecoFlag

                % What a failure puts back: the maps a partial fit overwrites, the flag
                % that describes them, the scale the fit sets, and the lamps
                previous.t1map = app.td.t1map;
                previous.m0map = app.td.m0map;
                previous.r2map = app.td.r2map;
                previous.validFitFlag = app.validFitFlag;
                previous.scale = [app.T1ScaleMinEditField.Value, app.T1ScaleEditField.Value];
                previous.lamps = {app.Lamp1.Color, app.Lamp2.Color, app.Lamp3.Color, app.Lamp4.Color, ...
                    app.Lamp5.Color, app.Lamp6.Color, app.Lamp7.Color};

                app.SetLights([2,2,2,2,3,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TabGroup3.SelectedTab = app.T1mapTab;
                app.TextMessage('Fitting T1 maps ...');
                app.validFitFlag = false;
                app.abortFitFlag = false;
                app.AbortFitButton.Enable = 'on';
                drawnow;

                try

                    % One struct describes this fit, gathered once, so that nothing
                    % below the app reads a widget.
                    fitParams = gatherFitParams(app);
                    app.LogParamsFcn('fit', fitParams);

                    app.MakeSelectionFcn;

                    if nnz(app.td.pointSelection) < t1mapp.Constants.MIN_FIT_POINTS
                        ME = MException('T1mapp:SelectionError', ...
                            'ERROR: at least %d points of the series must be selected for fitting ...', ...
                            t1mapp.Constants.MIN_FIT_POINTS);
                        throw(ME);
                    end

                    % The model-based fit is for 2D inversion recovery, from the
                    % measured k-space, so not after a registration
                    app.TextMessage(strcat("Fitting with the selected points: ", ...
                        num2str(round(app.td.fitValues(app.td.pointSelection)',1)), " ..."));
                    mobaAllowed = app.td.method == t1mapp.Constants.METHOD_IR && ~app.td.data3dFlag && ~fitParams.useRegistered;

                    if fitParams.moba && mobaAllowed
                        app.TextMessage('Model-based fitting ...');
                        app.FitModelBasedFcn(fitParams);
                    else
                        if fitParams.moba
                            app.TextMessage('WARNING: the model-based fit does not apply to these images, fitting pixel-wise ...');
                        end
                        app.FitPixelwiseFcn(fitParams);
                    end

                    if app.abortFitFlag
                        app.TextMessage('WARNING: fit aborted, the maps are incomplete ...');
                    else
                        app.TextMessage('Finished ...');
                    end

                    % Show the middle slice
                    app.validFitFlag = true;
                    app.ChangeMapSlices(round(app.td.ns/2));
                    app.ChangeMapIndex(app.FirstDynamicEditField.Value);
                    app.AutoScaleFcn;
                    app.PlotMaps;

                    % Reset buttons
                    app.AbortFitButton.Enable = 'off';
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: Something went wrong during fitting ...");

                    % The maps back as they were before the step, so a fit that failed
                    % halfway leaves no mix of new and old slices, and an earlier fit that
                    % was complete stays usable
                    app.td.t1map = previous.t1map;
                    app.td.m0map = previous.m0map;
                    app.td.r2map = previous.r2map;
                    app.validFitFlag = previous.validFitFlag;
                    app.T1ScaleMinEditField.Value = previous.scale(1);
                    app.T1ScaleEditField.Value = previous.scale(2);
                    for k = 1:numel(previous.lamps)
                        app.("Lamp" + k).Color = previous.lamps{k};
                    end
                    app.Lamp5.Color = app.red;

                    try
                        if app.validFitFlag
                            app.PlotMaps;
                        else
                            cla(app.T1MapFig);
                            cla(app.M0MapFig);
                            cla(app.R2MapFig);
                        end
                    catch
                    end

                    fitted = double(app.validFitFlag);
                    app.AbortFitButton.Enable = 'off';
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(fitted);
                    app.SetFitButtons(1);
                    app.SetButtons([1,1,1,1,1,fitted,fitted,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)

            app.abortFitFlag = true;
            drawnow;

        end

        % Window scroll wheel function: T1mappUIFigure
        function T1MappUIFigureWindowScrollWheel(app, event)

            % Scrolls through the slices of the raw images on the left half of the
            % window, and through the slices of the maps on the right half

            if app.td.ns > 1

                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);
                step = sign(event.VerticalScrollCount);

                if Xpos < 820

                    if app.validRecoFlag
                        app.SlicesEditField1.Value = min(max(1, app.SlicesEditField1.Value + step), app.td.ns);
                        app.SlicesEditFieldValue1Changed;
                    end

                elseif ~app.roiFlag && app.validFitFlag

                    app.ChangeMapSlices(app.SlicesEditField2.Value + step);
                    app.PlotMaps;

                end

                drawnow;

            end

        end

        % Value changed function: SlicesEditField2
        function SlicesEditField2ValueChanged(app, event)

            app.ChangeMapSlices(app.SlicesEditField2.Value);
            app.PlotMaps;

        end

        % Value changed function: SlicesSlider2
        function SlicesSlider2ValueChanged(app, event)

            app.ChangeMapSlices(app.SlicesSlider2.Value);
            app.PlotMaps;

        end

        % Value changed function: SlicesEditField3
        function SlicesEditField3ValueChanged(app, event)

            app.ChangeMapSlices(app.SlicesEditField3.Value);
            app.PlotMaps;

        end

        % Value changed function: SlicesSlider3
        function SlicesSlider3ValueChanged(app, event)

            app.ChangeMapSlices(app.SlicesSlider3.Value);
            app.PlotMaps;

        end

        % Value changed function: DynamicsEditField2
        function DynamicsEditField2ValueChanged(app, event)

            app.ChangeMapIndex(app.DynamicsEditField2.Value);
            app.PlotMaps;

        end

        % Value changed function: DynamicsSlider2
        function DynamicsSlider2ValueChanged(app, event)

            app.ChangeMapIndex(app.DynamicsSlider2.Value);
            app.PlotMaps;

        end

        % Value changed function: DynamicsEditField3
        function DynamicsEditField3ValueChanged(app, event)

            app.ChangeMapIndex(app.DynamicsEditField3.Value);
            app.PlotMaps;

        end

        % Value changed function: DynamicsSlider3
        function DynamicsSlider3ValueChanged(app, event)

            app.ChangeMapIndex(app.DynamicsSlider3.Value);
            app.PlotMaps;

        end

        % Value changed function: T1ScaleEditField
        function T1ScaleEditFieldValueChanged(app, event)

            if app.T1ScaleEditField.Value <= app.T1ScaleMinEditField.Value
                app.T1ScaleEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the T1 scale maximum has to lie above the minimum ...');
                return
            end

            app.PlotMaps;

        end

        % Value changed function: T1ScaleMinEditField
        function T1ScaleMinEditFieldValueChanged(app, event)

            if app.T1ScaleMinEditField.Value >= app.T1ScaleEditField.Value
                app.T1ScaleMinEditField.Value = event.PreviousValue;
                app.TextMessage('WARNING: the T1 scale minimum has to lie below the maximum ...');
                return
            end

            app.PlotMaps;

        end

        % Button pushed function: T1ScaleAutoButton
        function T1ScaleAutoButtonPushed(app, event)

            app.AutoScaleFcn;
            app.PlotMaps;

        end

        % Selection changed function: T1ColormapButtonGroup
        function T1ColormapButtonGroupSelectionChanged2(app, event)

            app.t1cmap = app.ColorMapFromButtonsFcn(1, app.t1cmap);
            app.PlotMaps;

        end

        % Selection changed function: M0ColorMapButtonGroup
        function M0ColorMapButtonGroupSelectionChanged(app, event)

            app.m0cmap = app.ColorMapFromButtonsFcn(2, app.m0cmap);
            app.PlotMaps;

        end

        % Selection changed function: R2ColorMapButtonGroup
        function R2ColorMapButtonGroupSelectionChanged(app, event)

            app.r2cmap = app.ColorMapFromButtonsFcn(3, app.r2cmap);
            app.PlotMaps;

        end

        % Image clicked function: T1Image
        function T1ImageClicked(app, event)

            if app.validFitFlag

                % The position of the app and of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the click in the image window, whose bottom-left corner
                % sits at (862, 412) in the app and which is 313 pixels square
                imagepos = [862,412];
                imsize = 313;
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.td.parameters.PHASE_ORIENTATION
                    aratio = app.td.aspectRatio;
                    xsize = app.td.dimy;
                    ysize = app.td.dimx;
                else
                    aratio = 1/app.td.aspectRatio;
                    xsize = app.td.dimx;
                    ysize = app.td.dimy;
                end

                % Correct for a non-square image
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Within the matrix
                % Pixel i covers (i-1, i] of the scaled position: ceil, round put the cross-hair
                % half a pixel off on average (2026-09-30)
                xcor = min(max(ceil(xcor),1),xsize);
                ycor = min(max(ceil(ycor),1),ysize);
                zcor = app.SlicesEditField2.Value;
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;

                if app.roiFlag

                    % Paint the points within the brush radius
                    se = strel("disk",app.BrushEditField.Value-1,0);   % the exact disk, 2r+1 wide (2026-09-30)
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - (size(se.Neighborhood)+1)/2;   % centred on the click: the radius was one short, brushes 1-3 painted beside it (2026-09-30)

                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    plotPoints = app.roiPoints(app.roiPoints(:,3)==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % A cross-hair
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(xcor,ycor,ysize);

                end

                % The resulting T1 values and fit
                app.CalculateRoiT1;

            end

        end

        % Image clicked function: M0Image
        function M0ImageClicked(app, event)

            app.T1ImageClicked;

        end

        % Button pushed function: ROIbutton, ROIbutton2
        function ROIbuttonButtonPushed(app, event)

            % Toggle between ROI drawing and the cross-hair

            app.roiFlag = strcmp(app.ROIbutton.Text,'ROI');

            if app.roiFlag
                app.ROIbutton.Text = 'Cross';
                app.ROIbutton2.Text = 'Cross';
                app.T1PixelValueLabel.Text = 'Pixel averaged';
                app.TextMessage('ROI drawing selected ...');
            else
                app.ROIbutton.Text = 'ROI';
                app.ROIbutton2.Text = 'ROI';
                app.T1PixelValueLabel.Text = 'Pixel value';
                app.TextMessage('Cross-hair selected ...');
            end

            visible = matlab.lang.OnOffSwitchState(app.roiFlag);
            app.EraseButton.Visible = visible;
            app.EraseButton2.Visible = visible;
            app.BrushEditField.Visible = visible;
            app.BrushEditFieldLabel.Visible = visible;
            app.BrushEditField2.Visible = visible;
            app.BrushEditField2Label.Visible = visible;
            app.T1ViewField2Label.Visible = visible;
            app.T1ViewField2.Visible = visible;
            app.T1ROIAveragedLabel.Visible = visible;

            app.roiPoints = [];
            delete(app.roiT1);
            delete(app.roiM0);
            delete(app.crossT1);
            delete(app.crossM0);
            app.PlotMaps;

        end

        % Button pushed function: EraseButton, EraseButton2
        function EraseButtonPushed(app, event)

            app.roiPoints = [];
            delete(app.roiT1);
            delete(app.roiM0);
            app.PlotMaps;

        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Value changed function: BrushEditField
        function BrushEditFieldValueChanged(app, event)

            app.BrushEditField2.Value = app.BrushEditField.Value;

        end

        % Value changed function: BrushEditField2
        function BrushEditField2ValueChanged(app, event)

            app.BrushEditField.Value = app.BrushEditField2.Value;

        end

        % Button pushed function: ExportRoiDataButton
        function ExportRoiDataButtonPushed(app, event)

            % Writes the ROI series -- the TIs, TRs or flip angles and the averaged
            % intensities with the sign the fit restored -- as a CSV file

            if ~app.validFitFlag || isempty(app.roiIntDataSigned)
                app.TextMessage('There is no ROI data to export: click in a map inside the mask first ...');
                return
            end

            try

                app.T1mappUIFigure.Visible = 'off';
                folder = uigetdir(app.roiExportFolder,'ROI data export folder ...');
                if ~app.closeRequested
                    app.T1mappUIFigure.Visible = 'on';
                end

                if ~ischar(folder)
                    app.TextMessage('User cancel ...');
                    return
                end

                app.roiExportFolder = folder;
                rdata = [app.td.fitValues(:), app.roiIntDataSigned(:)];
                fileName = fullfile(app.roiExportFolder, strcat('roiData-', app.td.tag, '.csv'));
                writematrix(rdata, fileName);
                app.TextMessage(strcat("ROI data exported to ", fileName, " ..."));
                app.SetStatus(0);

            catch ME

                if ~app.closeRequested
                    app.T1mappUIFigure.Visible = 'on';
                end
                app.TextMessage(ME.message);
                app.TextMessage('ERROR: the ROI data could not be exported ...');
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.dicomExportFolder;
                previousLamp = app.Lamp6.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,3,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    app.TextMessage('DICOM export ...');

                    % The MRD and Data folders share a base folder:
                    %   base / MRD / 343 / 8455 /
                    %   base / Data / 343 / DICOM / 8455 / [1|2|3] /
                    dcmSfolder{1} = replace(app.mrdImportFolder,"MRD","Data");
                    dcmIndx{1} = 2;
                    try
                        k = strfind(dcmSfolder{1},"Data");
                        fs = strfind(dcmSfolder{1}(k:end),filesep);
                        dcmSfolder{1} = dcmSfolder{1}(1:k+fs(3)-1);
                        dcmSfolder{1} = insertAfter(dcmSfolder{1},k+fs(2)-1,strcat("DICOM",filesep));
                    catch
                        dcmSfolder{1} = app.mrdImportFolder;
                    end
                    for n = 1:3
                        dcmSfolder{n+1} = strcat(dcmSfolder{1},num2str(n),filesep);
                        dcmIndx{n+1} = 3;
                    end

                    % Does one of the folders hold the scanner's DICOM files
                    dcmExist = false;
                    for i = 1:length(dcmSfolder)
                        if ~isempty(dir(strcat(dcmSfolder{i},"*.dcm")))
                            dcmFolder = dcmSfolder{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmFolder," ..."));
                            filesepIdx = strfind(dcmFolder, filesep);
                            app.dicomExportFolder = dcmFolder(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Export with the scanner's DICOM headers
                        try
                            mkdir(fullfile(app.dicomExportFolder,"testfolder"));
                            rmdir(fullfile(app.dicomExportFolder,"testfolder"));
                        catch
                            app.T1mappUIFigure.Visible = 'off';
                            app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                            if ~app.closeRequested
                                app.T1mappUIFigure.Visible = 'on';
                            end
                            drawnow;
                            if ~ischar(app.dicomExportFolder)
                                ME = MException('T1mapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        app.TextMessage('Export with DICOM information ...');
                        app.TextMessage(strcat("DICOM export folder = ",app.dicomExportFolder," ..."));
                        app.exportFolder = t1mapp.io.exportDicomT1dcm(app.td, app.dicomExportFolder, dcmFolder, AppReporter(app));

                    else

                        % Export with headers built from the acquisition parameters
                        app.T1mappUIFigure.Visible = 'off';
                        app.dicomExportFolder = uigetdir(app.dicomExportFolder,'DICOM export folder ...');
                        if ~app.closeRequested
                            app.T1mappUIFigure.Visible = 'on';
                        end
                        drawnow;
                        if ~ischar(app.dicomExportFolder)
                            ME = MException('T1mapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        app.exportFolder = strcat(fullfile(app.dicomExportFolder,"DICOM"),filesep);
                        app.TextMessage(strcat("DICOM export folder = ",app.exportFolder," ..."));
                        app.exportFolder = t1mapp.io.exportDicomT1(app.td, app.exportFolder, AppReporter(app));
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % NIfTI export
                    try
                        niftiFolder = t1mapp.io.exportNiftiFcn(app.td, app.dicomExportFolder, app.exportFolder, AppReporter(app));

                        % The same map as a BIDS quantitative map, in the seconds the format
                        % defines it in rather than the milliseconds everything else here uses
                        t1mapp.io.exportBidsFcn(app.td, app.dicomExportFolder, niftiFolder, AppReporter(app));
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfTI export failed ...");
                    end

                    % Success
                    app.TextMessage("DICOM export ready ...");
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.T1mappUIFigure.Visible = 'on';
                    end
                    app.dicomExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'T1mapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('DICOM export cancelled ...');
                        app.Lamp6.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                        app.SetLights([2,2,2,2,2,0,2]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)

            if app.validFitFlag

                % What a cancelled dialog or a failure puts back: the export folder, which
                % the settings may have set, and the lamp of this step
                previousFolder = app.gifExportFolder;
                previousLamp = app.Lamp7.Color;

                % Indicate busy
                app.SetLights([2,2,2,2,2,2,3]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    try
                        % base / Data / scan number /
                        gifOutputFolder = replace(app.mrdImportFolder, "MRD", "Data");
                        k = strfind(gifOutputFolder,"Data");
                        fs = strfind(gifOutputFolder(k:end),filesep);
                        gifOutputFolder = gifOutputFolder(1:k+fs(2)-1);
                        mkdir(fullfile(gifOutputFolder,"testfolder"));
                        rmdir(fullfile(gifOutputFolder,"testfolder"));
                    catch
                        app.T1mappUIFigure.Visible = 'off';
                        app.gifExportFolder = uigetdir(app.gifExportFolder,'GIF export folder ...');
                        if ~app.closeRequested
                            app.T1mappUIFigure.Visible = 'on';
                        end
                        if ~ischar(app.gifExportFolder)
                            ME = MException('T1mapp:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifOutputFolder = app.gifExportFolder;
                    end
                    gifOutputFolder = strcat(fullfile(gifOutputFolder,"GIF"),filesep);

                    app.TextMessage(strcat("GIF export folder = ",gifOutputFolder," ..."));
                    app.ExportGifsFcn(gifOutputFolder);
                    app.TextMessage('GIF export ready ...');

                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % The window is shown again in case the failure came while it was hidden
                    % behind the folder dialog, and the export folder is kept
                    if ~app.closeRequested
                        app.T1mappUIFigure.Visible = 'on';
                    end
                    app.gifExportFolder = previousFolder;
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetButtons([1,1,1,1,1,1,1,1]);

                    if strcmpi(ME.identifier, 'T1mapp:userCancel')
                        % Cancelling the folder dialog is not a failure
                        app.TextMessage('GIF export cancelled ...');
                        app.Lamp7.Color = previousLamp;
                        app.SetStatus(0);
                    else
                        app.TextMessage(ME.message);
                        app.TextMessage('ERROR: Something went wrong during GIF export ...');
                        app.SetLights([2,2,2,2,2,2,0]);
                        app.SetStatus(2);
                    end

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)

            % The log closed, the path restored and the app deleted
            app.CloseAppFcn;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T1mappUIFigure = uifigure('Visible', 'off');
            app.T1mappUIFigure.Name = 'T1mapp';
            app.T1mappUIFigure.Icon = fullfile(pathToMLAPP, 'T1icon.png');
            app.T1mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T1MappUIFigureWindowScrollWheel, true);
            app.MRSImage = uiimage(app.T1mappUIFigure);
            app.Tsub1subMappingLabel = uilabel(app.T1mappUIFigure);
            app.Tsub1subMappingLabel.Interpreter = 'html';
            app.Tsub1subMappingLabel.Text = 'T<sub>1</sub> Mapping';
            app.TulpLogo = uiimage(app.T1mappUIFigure);
            app.TabGroup1 = uitabgroup(app.T1mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load MRD data'};
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoImagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoImagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoImagesButtonPushed, true);
            app.RecoImagesButton.Enable = 'off';
            app.RecoImagesButton.Tooltip = {'Reconstruct the images, check CS reco for compressed-sensing reconstruction'};
            app.RecoImagesButton.Text = 'Reco images';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.CSrecoCheckBoxLabel = uilabel(app.DashboardTab);
            app.CSrecoCheckBoxLabel.Enable = 'off';
            app.CSrecoCheckBoxLabel.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBoxLabel.Text = 'CS reco';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Tooltip = {'Check for compressed-sensing reconstruction with Bart'};
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Text = '';
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register the images'};
            app.RegisterButton.Text = 'Register';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Image registration transformation method'};
            app.RegistrationDropDown.Value = 'Affine';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask the images to exclude fitting of background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitT1Button = uibutton(app.DashboardTab, 'push');
            app.FitT1Button.ButtonPushedFcn = createCallbackFcn(app, @FitT1ButtonPushed, true);
            app.FitT1Button.Enable = 'off';
            app.FitT1Button.Tooltip = {'Start the T1 fitting'};
            app.FitT1Button.Text = 'Fit T1';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.ModelBasedCheckBoxLabel = uilabel(app.DashboardTab);
            app.ModelBasedCheckBoxLabel.Enable = 'off';
            app.ModelBasedCheckBoxLabel.Tooltip = {'Check for model-based T1 fitting with Bart'};
            app.ModelBasedCheckBoxLabel.Text = 'model-based';
            app.ModelBasedCheckBox = uicheckbox(app.DashboardTab);
            app.ModelBasedCheckBox.Tooltip = {'Check for model-based T1 fitting with Bart'};
            app.ModelBasedCheckBox.Enable = 'off';
            app.ModelBasedCheckBox.Text = '';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the T1 fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM and NIfTI'};
            app.ExportDCMButton.Text = 'DCM / NIFTI';
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to animated GIF'};
            app.ExportGIFButton.Text = 'GIF';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.TabGroup2 = uitabgroup(app.T1mappUIFigure);
            app.RawImagesTab = uitab(app.TabGroup2);
            app.RawImagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawImagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawImagesTab);
            app.ImageLabel.Text = 'Image';
            app.RawImage = uiimage(app.RawImagesTab);
            app.ImageSelectionPanel = uipanel(app.RawImagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField1Label = uilabel(app.ImageSelectionPanel);
            app.SlicesEditField1Label.Text = 'slice';
            app.SlicesEditField1 = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField1.Limits = [1 Inf];
            app.SlicesEditField1.RoundFractionalValues = 'on';
            app.SlicesEditField1.ValueDisplayFormat = '%.0f';
            app.SlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.SlicesEditField1.Tooltip = {'Slice number'};
            app.SlicesEditField1.Value = 1;
            app.SlicesSlider1 = uislider(app.ImageSelectionPanel);
            app.SlicesSlider1.Limits = [1 200];
            app.SlicesSlider1.MajorTicks = [];
            app.SlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider1ValueChanged, true);
            app.SlicesSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider1.Tooltip = {'Slice number slider'};
            app.SlicesSlider1.Value = 1;
            app.InversionEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.InversionEditFieldLabel.Text = 'inversion';
            app.InversionEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.InversionEditField.Limits = [1 Inf];
            app.InversionEditField.RoundFractionalValues = 'on';
            app.InversionEditField.ValueDisplayFormat = '%.0f';
            app.InversionEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.InversionEditField.Tooltip = {'Inversion time number'};
            app.InversionEditField.Value = 1;
            app.InversionSlider = uislider(app.ImageSelectionPanel);
            app.InversionSlider.Limits = [1 200];
            app.InversionSlider.MajorTicks = [];
            app.InversionSlider.ValueChangedFcn = createCallbackFcn(app, @InversionSliderValueChanged, true);
            app.InversionSlider.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.InversionSlider.Tooltip = {'Inversion time number slider'};
            app.InversionSlider.Value = 1;
            app.DynamicsEditField1Label = uilabel(app.ImageSelectionPanel);
            app.DynamicsEditField1Label.Text = 'dynamic';
            app.DynamicsEditField1 = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.DynamicsEditField1.Limits = [1 Inf];
            app.DynamicsEditField1.RoundFractionalValues = 'on';
            app.DynamicsEditField1.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.DynamicsEditField1.Tooltip = {'Dynamic number'};
            app.DynamicsEditField1.Value = 1;
            app.DynamicsSlider1 = uislider(app.ImageSelectionPanel);
            app.DynamicsSlider1.Limits = [1 200];
            app.DynamicsSlider1.MajorTicks = [];
            app.DynamicsSlider1.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider1ValueChanged, true);
            app.DynamicsSlider1.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider1.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider1.Value = 1;
            app.MaskSettingsPanel = uipanel(app.RawImagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.thresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.thresholdEditField.Limits = [0 Inf];
            app.thresholdEditField.ValueDisplayFormat = '%.0f';
            app.thresholdEditField.ValueChangedFcn = createCallbackFcn(app, @thresholdEditFieldValueChanged, true);
            app.thresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 1001 2001 3001 4001 5001 6001 7001 8001 9001 10001 11001 12001 13001 14001 15001 16384];
            app.ThresholdSlider.Tooltip = {'Mask threshold slider'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.UndoButton = uibutton(app.MaskSettingsPanel, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Tooltip = {'Undo the last erode, dilate, close or open'};
            app.UndoButton.Text = 'Undo';
            app.MaskSizeEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.MaskSizeEditField.Limits = [1 10];
            app.MaskSizeEditField.RoundFractionalValues = 'on';
            app.MaskSizeEditField.ValueDisplayFormat = '%.0f';
            app.MaskSizeEditField.Tooltip = {'Size of erode, dilate, close and open, in pixels'};
            app.MaskSizeEditField.Value = 1;
            app.WindowEditFieldLabel = uilabel(app.RawImagesTab);
            app.WindowEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.WindowEditField.Limits = [0 32768];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelEditFieldLabel = uilabel(app.RawImagesTab);
            app.LevelEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawImagesTab, 'numeric');
            app.LevelEditField.Limits = [0 32768];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.AutoLWButton = uibutton(app.RawImagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WindowSlider = uislider(app.RawImagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawImagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.RecoParametersTab = uitab(app.TabGroup2);
            app.RecoParametersTab.Title = 'Reco parameters';
            app.FilteringPanel = uipanel(app.RecoParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.DeNoiseCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeNoiseCheckBoxLabel.Enable = 'off';
            app.DeNoiseCheckBoxLabel.Tooltip = {'PCA de-noising filter'};
            app.DeNoiseCheckBoxLabel.Text = 'de-noise';
            app.DeNoiseCheckBox = uicheckbox(app.FilteringPanel);
            app.DeNoiseCheckBox.Tooltip = {'Check for PCA de-noising'};
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseCheckBox.Value = true;
            app.DeRingCheckBoxLabel = uilabel(app.FilteringPanel);
            app.DeRingCheckBoxLabel.Enable = 'off';
            app.DeRingCheckBoxLabel.Tooltip = {'Gibbs ringing suppression filter'};
            app.DeRingCheckBoxLabel.Text = 'de-ring';
            app.DeRingCheckBox = uicheckbox(app.FilteringPanel);
            app.DeRingCheckBox.Tooltip = {'Check to apply Gibbs ringing suppression filter'};
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Text = '';
            app.CSregularizationPanel = uipanel(app.RecoParametersTab);
            app.CSregularizationPanel.Title = 'CS regularization';
            app.WVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditField.Value = 0.001;
            app.TVxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditField.Value = 0.001;
            app.LLRxyzEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally-low-rank regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.LLRxyzEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally-low-rank regularization in the spatial dimensions'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVtiEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVtiEditFieldLabel.Tooltip = {'Total-variation regularization in the TI dimension'};
            app.TVtiEditFieldLabel.Text = 'TVti';
            app.TVtiEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVtiEditField.Limits = [0 20];
            app.TVtiEditField.ValueDisplayFormat = '%.4f';
            app.TVtiEditField.Enable = 'off';
            app.TVtiEditField.Tooltip = {'Total-variation regularization in the TI dimension'};
            app.TVdynEditFieldLabel = uilabel(app.CSregularizationPanel);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.TVdynEditField = uieditfield(app.CSregularizationPanel, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.FitParametersTab = uitab(app.TabGroup2);
            app.FitParametersTab.Title = 'Fit parameters';
            app.SliceSelectionPanel = uipanel(app.FitParametersTab);
            app.SliceSelectionPanel.Title = 'Slice selection';
            app.FirstSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.FirstSliceEditFieldLabel.Text = 'first slice';
            app.FirstSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.RoundFractionalValues = 'on';
            app.FirstSliceEditField.ValueDisplayFormat = '%.0f';
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Tooltip = {'First fit slice'};
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditFieldLabel = uilabel(app.SliceSelectionPanel);
            app.LastSliceEditFieldLabel.Text = 'last slice';
            app.LastSliceEditField = uieditfield(app.SliceSelectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.RoundFractionalValues = 'on';
            app.LastSliceEditField.ValueDisplayFormat = '%.0f';
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Tooltip = {'Last fit slice'};
            app.LastSliceEditField.Value = 1;
            app.DynamicsSelectionPanel = uipanel(app.FitParametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.FirstDynamicEditFieldLabel.Text = 'first dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.RoundFractionalValues = 'on';
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.FirstDynamicEditField.Tooltip = {'First fit dynamic'};
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditFieldLabel = uilabel(app.DynamicsSelectionPanel);
            app.LastDynamicEditFieldLabel.Text = 'last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.RoundFractionalValues = 'on';
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.LastDynamicEditField.Tooltip = {'Last fit dynamic'};
            app.LastDynamicEditField.Value = 1;
            app.GoodnessOfFitPanel = uipanel(app.FitParametersTab);
            app.GoodnessOfFitPanel.Title = 'Goodness of fit';
            app.RsquareLabel = uilabel(app.GoodnessOfFitPanel);
            app.RsquareLabel.Text = 'min R-square';
            app.Rsquare = uieditfield(app.GoodnessOfFitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Tooltip = {'R-square fit threshold'};
            app.Rsquare.Value = 0.8;
            app.InversionTimeSelectionPanel = uipanel(app.FitParametersTab);
            app.InversionTimeSelectionPanel.Tooltip = {'Inversion time selection'};
            app.InversionTimeSelectionPanel.Title = 'Inversion time selection';
            app.CheckBox_1 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_9 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_17 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_25 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_33 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_41 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_49 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_57 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_65 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_65.Text = '65';
            app.CheckBox_65.Value = true;
            app.CheckBox_73 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_73.Text = '73';
            app.CheckBox_73.Value = true;
            app.CheckBox_81 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_81.Text = '81';
            app.CheckBox_81.Value = true;
            app.CheckBox_89 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_89.Text = '89';
            app.CheckBox_89.Value = true;
            app.CheckBox_2 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_10 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_18 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_26 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_34 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_42 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_50 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_58 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_66 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_66.Text = '66';
            app.CheckBox_66.Value = true;
            app.CheckBox_74 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_74.Text = '74';
            app.CheckBox_74.Value = true;
            app.CheckBox_82 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_82.Text = '82';
            app.CheckBox_82.Value = true;
            app.CheckBox_90 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_90.Text = '90';
            app.CheckBox_90.Value = true;
            app.CheckBox_3 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_11 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_19 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_27 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_35 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_43 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_51 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_59 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_67 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_67.Text = '67';
            app.CheckBox_67.Value = true;
            app.CheckBox_75 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_75.Text = '75';
            app.CheckBox_75.Value = true;
            app.CheckBox_83 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_83.Text = '83';
            app.CheckBox_83.Value = true;
            app.CheckBox_91 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_91.Text = '91';
            app.CheckBox_91.Value = true;
            app.CheckBox_4 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_12 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_20 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_28 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_36 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_44 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_52 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_60 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_68 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_68.Text = '68';
            app.CheckBox_68.Value = true;
            app.CheckBox_76 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_76.Text = '76';
            app.CheckBox_76.Value = true;
            app.CheckBox_84 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_84.Text = '84';
            app.CheckBox_84.Value = true;
            app.CheckBox_92 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_92.Text = '92';
            app.CheckBox_92.Value = true;
            app.CheckBox_5 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_13 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_21 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_29 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_37 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_45 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_53 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_61 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_69 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_69.Text = '69';
            app.CheckBox_69.Value = true;
            app.CheckBox_77 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_77.Text = '77';
            app.CheckBox_77.Value = true;
            app.CheckBox_85 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_85.Text = '85';
            app.CheckBox_85.Value = true;
            app.CheckBox_93 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_93.Text = '93';
            app.CheckBox_93.Value = true;
            app.CheckBox_6 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_14 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_22 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_30 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_38 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_46 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_54 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_62 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_70 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_70.Text = '70';
            app.CheckBox_70.Value = true;
            app.CheckBox_78 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_78.Text = '78';
            app.CheckBox_78.Value = true;
            app.CheckBox_86 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_86.Text = '86';
            app.CheckBox_86.Value = true;
            app.CheckBox_94 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_94.Text = '94';
            app.CheckBox_94.Value = true;
            app.CheckBox_7 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_15 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_23 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_31 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_39 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_47 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_55 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_63 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_71 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_71.Text = '71';
            app.CheckBox_71.Value = true;
            app.CheckBox_79 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_79.Text = '79';
            app.CheckBox_79.Value = true;
            app.CheckBox_87 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_87.Text = '87';
            app.CheckBox_87.Value = true;
            app.CheckBox_95 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_95.Text = '95';
            app.CheckBox_95.Value = true;
            app.CheckBox_8 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_16 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_24 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_32 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_40 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_48 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_56 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_64 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.CheckBox_72 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_72.Text = '72';
            app.CheckBox_72.Value = true;
            app.CheckBox_80 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_80.Text = '80';
            app.CheckBox_80.Value = true;
            app.CheckBox_88 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_88.Text = '88';
            app.CheckBox_88.Value = true;
            app.CheckBox_96 = uicheckbox(app.InversionTimeSelectionPanel);
            app.CheckBox_96.Text = '96';
            app.CheckBox_96.Value = true;
            app.AppSettingsTab = uitab(app.TabGroup2);
            app.AppSettingsTab.Title = 'App settings';
            app.ColorsPanel = uipanel(app.AppSettingsTab);
            app.ColorsPanel.Title = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.ColorsPanel);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.SoundsPanel = uipanel(app.AppSettingsTab);
            app.SoundsPanel.Title = 'Sounds';
            app.AutoSoundsCheckBoxLabel = uilabel(app.SoundsPanel);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.AutoSoundsCheckBox = uicheckbox(app.SoundsPanel);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.ImportSettingsPanel = uipanel(app.AppSettingsTab);
            app.ImportSettingsPanel.Title = 'Import settings';
            app.ToggleReorderCheckBoxLabel = uilabel(app.ImportSettingsPanel);
            app.ToggleReorderCheckBoxLabel.Tooltip = {'Check this button if imported images seem not reconstructed properly. This can be caused by a different version of the FLASH sequence.'};
            app.ToggleReorderCheckBoxLabel.Text = 'toggle reorder';
            app.ToggleReorderCheckBox = uicheckbox(app.ImportSettingsPanel);
            app.ToggleReorderCheckBox.Tooltip = {'Check this button if imported images seem not reconstructed properly. This can be caused by a different version of the FLASH sequence.'};
            app.ToggleReorderCheckBox.Text = '';
            app.TabGroup3 = uitabgroup(app.T1mappUIFigure);
            app.T1mapTab = uitab(app.TabGroup3);
            app.T1mapTab.Title = 'T1 map';
            app.T1MapFig = uiaxes(app.T1mapTab);
            app.T1MapFig.XTick = [];
            app.T1MapFig.YTick = [];
            app.T1linearPlot = uiaxes(app.T1mapTab);
            xlabel(app.T1linearPlot, 'TI')
            ylabel(app.T1linearPlot, 'Signal')
            app.T1MapLabel = uilabel(app.T1mapTab);
            app.T1MapLabel.Text = 'T1 map';
            app.T1Image = uiimage(app.T1mapTab);
            app.T1Image.ImageClickedFcn = createCallbackFcn(app, @T1ImageClicked, true);
            app.T1ImageSelectionPanel = uipanel(app.T1mapTab);
            app.T1ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField2Label = uilabel(app.T1ImageSelectionPanel);
            app.SlicesEditField2Label.Text = 'slice';
            app.SlicesEditField2 = uieditfield(app.T1ImageSelectionPanel, 'numeric');
            app.SlicesEditField2.Limits = [1 Inf];
            app.SlicesEditField2.RoundFractionalValues = 'on';
            app.SlicesEditField2.ValueDisplayFormat = '%.0f';
            app.SlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField2ValueChanged, true);
            app.SlicesEditField2.Tooltip = {'Slice number'};
            app.SlicesEditField2.Value = 1;
            app.SlicesSlider2 = uislider(app.T1ImageSelectionPanel);
            app.SlicesSlider2.Limits = [1 200];
            app.SlicesSlider2.MajorTicks = [];
            app.SlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider2ValueChanged, true);
            app.SlicesSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider2.Tooltip = {'Slice number slider'};
            app.SlicesSlider2.Value = 1;
            app.DynamicsEditField2Label = uilabel(app.T1ImageSelectionPanel);
            app.DynamicsEditField2Label.Text = 'dynamic';
            app.DynamicsEditField2 = uieditfield(app.T1ImageSelectionPanel, 'numeric');
            app.DynamicsEditField2.Limits = [1 Inf];
            app.DynamicsEditField2.RoundFractionalValues = 'on';
            app.DynamicsEditField2.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField2.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField2ValueChanged, true);
            app.DynamicsEditField2.Tooltip = {'Dynamic number'};
            app.DynamicsEditField2.Value = 1;
            app.DynamicsSlider2 = uislider(app.T1ImageSelectionPanel);
            app.DynamicsSlider2.Limits = [1 200];
            app.DynamicsSlider2.MajorTicks = [];
            app.DynamicsSlider2.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider2ValueChanged, true);
            app.DynamicsSlider2.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider2.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider2.Value = 1;
            app.T1ColormapButtonGroup = uibuttongroup(app.T1mapTab);
            app.T1ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @T1ColormapButtonGroupSelectionChanged2, true);
            app.T1ColormapButtonGroup.Title = 'Color map';
            app.T1ScaleMaxLabel = uilabel(app.T1ColormapButtonGroup);
            app.T1ScaleMaxLabel.Text = 'max';
            app.grayButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Interpreter = 'html';
            app.T1ScaleEditField = uieditfield(app.T1ColormapButtonGroup, 'numeric');
            app.T1ScaleEditField.Limits = [1 Inf];
            app.T1ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T1ScaleEditFieldValueChanged, true);
            app.T1ScaleEditField.Tooltip = {'Upper end of the T1 map scale (ms)'};
            app.T1ScaleEditField.Value = 1;
            app.T1ScaleMinLabel = uilabel(app.T1ColormapButtonGroup);
            app.T1ScaleMinLabel.Text = 'min';
            app.T1ScaleMinEditField = uieditfield(app.T1ColormapButtonGroup, 'numeric');
            app.T1ScaleMinEditField.Limits = [0 Inf];
            app.T1ScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @T1ScaleMinEditFieldValueChanged, true);
            app.T1ScaleMinEditField.Tooltip = {'Lower end of the T1 map scale (ms)'};
            app.autumnButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.autumnButton.Interpreter = 'html';
            app.T1ScaleAutoButton = uibutton(app.T1ColormapButtonGroup, 'push');
            app.T1ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T1ScaleAutoButtonPushed, true);
            app.T1ScaleAutoButton.Tooltip = {'Automatic T1 map scale setting'};
            app.T1ScaleAutoButton.Text = 'Auto';
            app.winterButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.winterButton.Interpreter = 'html';
            app.parulaButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Interpreter = 'html';
            app.lipariButton = uiradiobutton(app.T1ColormapButtonGroup);
            app.lipariButton.Text = 'lipari';
            app.lipariButton.Interpreter = 'html';
            app.lipariButton.Value = true;
            app.EraseButton = uibutton(app.T1mapTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Tooltip = {'Erase ROI'};
            app.EraseButton.Text = 'Erase';
            app.ROIbutton = uibutton(app.T1mapTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton.Text = 'ROI';
            app.BrushEditFieldLabel = uilabel(app.T1mapTab);
            app.BrushEditFieldLabel.Visible = 'off';
            app.BrushEditFieldLabel.Text = 'brush';
            app.BrushEditField = uieditfield(app.T1mapTab, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.RoundFractionalValues = 'on';
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.ValueChangedFcn = createCallbackFcn(app, @BrushEditFieldValueChanged, true);
            app.BrushEditField.Visible = 'off';
            app.BrushEditField.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField.Value = 2;
            app.XEditFieldLabel = uilabel(app.T1mapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.T1mapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.XEditField.Tooltip = {'X-coordinate'};
            app.YEditFieldLabel = uilabel(app.T1mapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.T1mapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'Y-coordinate'};
            app.T1PixelValueLabel = uilabel(app.T1mapTab);
            app.T1PixelValueLabel.Text = 'pixel value';
            app.T1ViewField1Label = uilabel(app.T1mapTab);
            app.T1ViewField1Label.Text = 'T1 (ms)';
            app.T1ViewField1 = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField1.Editable = 'off';
            app.T1ViewField1.Tooltip = {'T1 value'};
            app.T1ROIAveragedLabel = uilabel(app.T1mapTab);
            app.T1ROIAveragedLabel.Visible = 'off';
            app.T1ROIAveragedLabel.Text = 'ROI averaged';
            app.T1ViewField2Label = uilabel(app.T1mapTab);
            app.T1ViewField2Label.Visible = 'off';
            app.T1ViewField2Label.Text = 'T1 (ms)';
            app.T1ViewField2 = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField2.Editable = 'off';
            app.T1ViewField2.Visible = 'off';
            app.T1ViewField2.Tooltip = {'ROI-averaged T1 value'};
            app.ExportRoiDataButton = uibutton(app.T1mapTab, 'push');
            app.ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRoiDataButtonPushed, true);
            app.ExportRoiDataButton.Tooltip = {'Export signal as function of TI, TR or flip angle in ROI to CSV-file'};
            app.ExportRoiDataButton.Text = 'Export';
            app.M0R2mapTab = uitab(app.TabGroup3);
            app.M0R2mapTab.Title = 'M0 & R²';
            app.M0MapFig = uiaxes(app.M0R2mapTab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2mapTab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2mapTab);
            app.M0MapLabel.Text = 'M0 map';
            app.M0Image = uiimage(app.M0R2mapTab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @M0ImageClicked, true);
            app.M0ImageSelectionPanel = uipanel(app.M0R2mapTab);
            app.M0ImageSelectionPanel.Title = 'Image selection';
            app.SlicesEditField3Label = uilabel(app.M0ImageSelectionPanel);
            app.SlicesEditField3Label.Text = 'slice';
            app.SlicesEditField3 = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.SlicesEditField3.Limits = [1 Inf];
            app.SlicesEditField3.RoundFractionalValues = 'on';
            app.SlicesEditField3.ValueDisplayFormat = '%.0f';
            app.SlicesEditField3.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField3ValueChanged, true);
            app.SlicesEditField3.Tooltip = {'Slice number'};
            app.SlicesEditField3.Value = 1;
            app.SlicesSlider3 = uislider(app.M0ImageSelectionPanel);
            app.SlicesSlider3.Limits = [1 200];
            app.SlicesSlider3.MajorTicks = [];
            app.SlicesSlider3.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider3ValueChanged, true);
            app.SlicesSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.SlicesSlider3.Tooltip = {'Slice number slider'};
            app.SlicesSlider3.Value = 1;
            app.DynamicsEditField3Label = uilabel(app.M0ImageSelectionPanel);
            app.DynamicsEditField3Label.Text = 'dynamic';
            app.DynamicsEditField3 = uieditfield(app.M0ImageSelectionPanel, 'numeric');
            app.DynamicsEditField3.Limits = [1 Inf];
            app.DynamicsEditField3.RoundFractionalValues = 'on';
            app.DynamicsEditField3.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField3.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField3ValueChanged, true);
            app.DynamicsEditField3.Tooltip = {'Dynamic number'};
            app.DynamicsEditField3.Value = 1;
            app.DynamicsSlider3 = uislider(app.M0ImageSelectionPanel);
            app.DynamicsSlider3.Limits = [1 200];
            app.DynamicsSlider3.MajorTicks = [];
            app.DynamicsSlider3.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider3ValueChanged, true);
            app.DynamicsSlider3.MinorTicks = [1 10 19 28 37 46 55 64 73 82 91 100 109 118 127 136 145 154 163 172 181 190 200];
            app.DynamicsSlider3.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider3.Value = 1;
            app.M0ColorMapButtonGroup = uibuttongroup(app.M0R2mapTab);
            app.M0ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @M0ColorMapButtonGroupSelectionChanged, true);
            app.M0ColorMapButtonGroup.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Interpreter = 'html';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.autumnButton2.Text = 'autumn';
            app.autumnButton2.Interpreter = 'html';
            app.winterButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.winterButton2.Text = 'winter';
            app.winterButton2.Interpreter = 'html';
            app.parulaButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.parulaButton2.Text = 'parula';
            app.parulaButton2.Interpreter = 'html';
            app.lipariButton2 = uiradiobutton(app.M0ColorMapButtonGroup);
            app.lipariButton2.Text = 'lipari';
            app.lipariButton2.Interpreter = 'html';
            app.R2MapLabel = uilabel(app.M0R2mapTab);
            app.R2MapLabel.Text = 'R² map';
            app.EraseButton2 = uibutton(app.M0R2mapTab, 'push');
            app.EraseButton2.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton2.Visible = 'off';
            app.EraseButton2.Tooltip = {'Erase ROI'};
            app.EraseButton2.Text = 'Erase';
            app.ROIbutton2 = uibutton(app.M0R2mapTab, 'push');
            app.ROIbutton2.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton2.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton2.Text = 'ROI';
            app.R2Image = uiimage(app.M0R2mapTab);
            app.BrushEditField2Label = uilabel(app.M0R2mapTab);
            app.BrushEditField2Label.Visible = 'off';
            app.BrushEditField2Label.Text = 'brush';
            app.BrushEditField2 = uieditfield(app.M0R2mapTab, 'numeric');
            app.BrushEditField2.Limits = [1 5];
            app.BrushEditField2.RoundFractionalValues = 'on';
            app.BrushEditField2.ValueDisplayFormat = '%.0f';
            app.BrushEditField2.ValueChangedFcn = createCallbackFcn(app, @BrushEditField2ValueChanged, true);
            app.BrushEditField2.Visible = 'off';
            app.BrushEditField2.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField2.Value = 2;
            app.R2ColorMapButtonGroup = uibuttongroup(app.M0R2mapTab);
            app.R2ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @R2ColorMapButtonGroupSelectionChanged, true);
            app.R2ColorMapButtonGroup.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.grayButton3.Text = 'gray';
            app.grayButton3.Interpreter = 'html';
            app.autumnButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.autumnButton3.Text = 'autumn';
            app.autumnButton3.Interpreter = 'html';
            app.winterButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.winterButton3.Text = 'winter';
            app.winterButton3.Interpreter = 'html';
            app.parulaButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Interpreter = 'html';
            app.lipariButton3 = uiradiobutton(app.R2ColorMapButtonGroup);
            app.lipariButton3.Text = 'lipari';
            app.lipariButton3.Interpreter = 'html';
            app.lipariButton3.Value = true;
            app.TabGroup4 = uitabgroup(app.T1mappUIFigure);
            app.SequenceParametersTab = uitab(app.TabGroup4);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.DatafileEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.DatafileEditFieldLabel.Text = 'datafile';
            app.DatafileEditField = uieditfield(app.SequenceParametersTab, 'text');
            app.DatafileEditField.Editable = 'off';
            app.DatafileEditField.Tooltip = {'Data file name'};
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.TR0ViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TR0ViewFieldLabel.Text = '1st TR (ms)';
            app.TR0ViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TR0ViewField.Limits = [0 Inf];
            app.TR0ViewField.Editable = 'off';
            app.TR0ViewField.Tooltip = {'First repetition time'};
            app.dimxViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimxViewFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.TI0EditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TI0EditFieldLabel.Text = '1st TI (ms)';
            app.TI0EditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TI0EditField.Limits = [0 Inf];
            app.TI0EditField.ValueChangedFcn = createCallbackFcn(app, @TIsEditFieldValueChanged, true);
            app.TI0EditField.Editable = 'off';
            app.TI0EditField.Tooltip = {'First inversion time'};
            app.TIsEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TIsEditFieldLabel.Text = 'last TI (ms)';
            app.TIsEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TIsEditField.Limits = [0 Inf];
            app.TIsEditField.ValueChangedFcn = createCallbackFcn(app, @TIsEditFieldValueChanged, true);
            app.TIsEditField.Editable = 'off';
            app.TIsEditField.Tooltip = {'Last inversion time'};
            app.dimyViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.dimyViewFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.NrTIsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrTIsViewFieldLabel.Text = '#TIs';
            app.NrTIsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrTIsViewField.Limits = [0 Inf];
            app.NrTIsViewField.ValueDisplayFormat = '%.0f';
            app.NrTIsViewField.Editable = 'off';
            app.NrTIsViewField.Tooltip = {'Number of inversion times'};
            app.NrDynamicsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrDynamicsViewFieldLabel.Text = '#dynamics';
            app.NrDynamicsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrDynamicsViewField.Limits = [0 Inf];
            app.NrDynamicsViewField.ValueDisplayFormat = '%.0f';
            app.NrDynamicsViewField.Editable = 'off';
            app.NrDynamicsViewField.Tooltip = {'Number of dynamics'};
            app.SlicesViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SlicesViewFieldLabel.Text = '#slices';
            app.SlicesViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.AspectRatioViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.AspectRatioViewFieldLabel.Text = 'aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.SLTViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SLTViewFieldLabel.Text = 'slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.TRsEditFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRsEditFieldLabel.Text = 'last TR (ms)';
            app.TRsEditField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRsEditField.Limits = [0 Inf];
            app.TRsEditField.Editable = 'off';
            app.TRsEditField.Tooltip = {'Last repetition time'};
            app.NrTRsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.NrTRsViewFieldLabel.Text = '#TRs';
            app.NrTRsViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.NrTRsViewField.Limits = [0 Inf];
            app.NrTRsViewField.ValueDisplayFormat = '%.0f';
            app.NrTRsViewField.Editable = 'off';
            app.NrTRsViewField.Tooltip = {'Number of repetition times'};
            app.MessageBox = uitextarea(app.T1mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.T1mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.T1mappUIFigure);
            app.StatusLabel = uilabel(app.T1mappUIFigure);
            app.StatusLabel.Text = 'STATUS';
            app.CreditsLabel = uilabel(app.T1mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0.0';
            app.Hyperlink = uihyperlink(app.T1mappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'GitHub';
            % Show the figure after all components are created
            app.T1mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T1mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T1mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappUIFigure)
        end
    end
end